classdef p2_StandaloneApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        p3_MessageLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        p3_Message                    matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        p2_FreePlotTab                matlab.ui.container.Tab
        GridLayout9                   matlab.ui.container.GridLayout
        p3_hidecontrol                matlab.ui.control.Button
        p3_filegridlayout             matlab.ui.container.GridLayout
        p3_defaultorderbutton         matlab.ui.control.Button
        p3_customorderbutton          matlab.ui.control.Button
        p3_plotbutton                 matlab.ui.control.Button
        p3_ManualscalingPanel         matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        p3_scalinggrid                matlab.ui.control.DropDown
        p3_scalingaxisgridlabel       matlab.ui.control.Label
        p3_scalinglog                 matlab.ui.control.CheckBox
        p3_scalingloglabel            matlab.ui.control.Label
        p3_scalingaxiscolor           matlab.ui.control.DropDown
        p3_scalingcolorlabel          matlab.ui.control.Label
        p3_scalingaxislabel           matlab.ui.control.EditField
        p3_scalingaxislabellabel      matlab.ui.control.Label
        p3_scalingaxisname            matlab.ui.control.DropDown
        p3_scalingmodelabel           matlab.ui.control.Label
        p3_scalingmode                matlab.ui.control.CheckBox
        p3_scalingmsteplabel          matlab.ui.control.Label
        p3_scalingmaxlabel            matlab.ui.control.Label
        p3_scalingminlabel            matlab.ui.control.Label
        p3_scalingstep                matlab.ui.control.NumericEditField
        p3_scalingmax                 matlab.ui.control.NumericEditField
        p3_scalingmin                 matlab.ui.control.NumericEditField
        p3_scalingaxisnamelabel       matlab.ui.control.Label
        p3_UIAxes                     matlab.ui.control.UIAxes
        p2_ResultTab                  matlab.ui.container.Tab
        p2_opt7                       matlab.ui.control.CheckBox
        p2_opt6                       matlab.ui.control.CheckBox
        p2_opt5                       matlab.ui.control.CheckBox
        p2_opt4                       matlab.ui.control.CheckBox
        p2_opt3                       matlab.ui.control.CheckBox
        p2_opt2                       matlab.ui.control.CheckBox
        p2_opt1                       matlab.ui.control.CheckBox
        p2_opt0                       matlab.ui.control.CheckBox
        p2_t99_Switch                 matlab.ui.control.Switch
        p2_SelectFileButton           matlab.ui.control.Button
        p2_UITable2                   matlab.ui.control.Table
        p2_t21Switch                  matlab.ui.control.Switch
        p2_torquecbx                  matlab.ui.control.CheckBox
        p2_speedcbx                   matlab.ui.control.CheckBox
        p2_voltagecbx                 matlab.ui.control.CheckBox
        p2_GraphLabel                 matlab.ui.control.Label
        p2_Label                      matlab.ui.control.Label
        p2_TableLabel                 matlab.ui.control.Label
        p2_StepEditField              matlab.ui.control.NumericEditField
        StepEditFieldLabel            matlab.ui.control.Label
        p2_SpeedSetValueDropDown      matlab.ui.control.DropDown
        SpeedSetValueDropDownLabel    matlab.ui.control.Label
        p2_ButtonGroup_2              matlab.ui.container.ButtonGroup
        p2_T40ch2Button               matlab.ui.control.RadioButton
        p2_T40ch1Button               matlab.ui.control.RadioButton
        p2_VoltageSetValueDropDown    matlab.ui.control.DropDown
        VoltageSetValueDropDownLabel  matlab.ui.control.Label
        p2_TorqueSetValueDropDown     matlab.ui.control.DropDown
        TorqueSetValueDropDownLabel   matlab.ui.control.Label
        p2_ManualScalingCheckBox      matlab.ui.control.CheckBox
        p2_YmaxrightEditField_2       matlab.ui.control.NumericEditField
        YmaxrightEditField_2Label     matlab.ui.control.Label
        p2_YminrightEditField         matlab.ui.control.NumericEditField
        YminrightEditFieldLabel       matlab.ui.control.Label
        p2_YmaxleftEditField          matlab.ui.control.NumericEditField
        YmaxleftEditFieldLabel        matlab.ui.control.Label
        p2_YminleftEditField          matlab.ui.control.NumericEditField
        YminleftEditFieldLabel        matlab.ui.control.Label
        p2_XmaxEditField              matlab.ui.control.NumericEditField
        XmaxEditFieldLabel            matlab.ui.control.Label
        p2_XminEditField              matlab.ui.control.NumericEditField
        XminEditFieldLabel            matlab.ui.control.Label
        p2_PlotButton                 matlab.ui.control.Button
        p2_UIAxes4                    matlab.ui.control.UIAxes
        p2_UIAxes3                    matlab.ui.control.UIAxes
        p2_UIAxes2                    matlab.ui.control.UIAxes
        p2_UIAxes                     matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        HideMenu                      matlab.ui.container.Menu
        ShowMenu                      matlab.ui.container.Menu
        XaxisMenu                     matlab.ui.container.Menu
        YaxisleftMenu                 matlab.ui.container.Menu
        YaxisrightMenu                matlab.ui.container.Menu
    end

    properties (Access = public)   
        p2_data;
        p2_variables;
        p2_speedsetval;
        p2_voltagesetval;
        p2_string0;p2_string1;p2_string2;p2_string3;p2_string4;p2_string5;
        p2_string6;p2_string7;p2_string8;p2_string9;p2_string10;
        p2_string11;p2_string12;p2_string13;p2_string14;p2_string15;
        p2_string16;p2_string17;p2_string18;p2_string19;p2_string20; 
        p2_string21;
        p2_string99; 
        p2_strct=struct;
        p2_t21=struct;
        p2_plotbtn=0;
        p2_t99p1;p2_t99p2;p2_t99p3;p2_t99p4;
        p2_t99p5;p2_t99p6;p2_t99p7;p2_t99p8;
        p2_t99p9;p2_t99p10;p2_t99p11;p2_t99p12;

        p3_data;
        p3_variables;
        p3_axisnumber;
        p3_scaling_struct=struct;
        p3_UIFigure2;p3_UIFigure3;p3_UIFigure4;
        GridLayout2_3;GridLayout2_5;GridLayout2_6;

        p3_xparameterlabel;
        p3_xparameter1;

        p3_ylmodelabel;p3_ylcheckbox1;p3_ylcheckbox2;p3_ylcheckbox3;p3_ylcheckbox4;p3_ylcheckbox5;p3_ylcheckbox6;p3_ylcheckbox7;p3_ylcheckbox8;
        p3_ylparameterlabel;
        p3_ylparameter1;p3_ylparameter2;p3_ylparameter3;p3_ylparameter4;p3_ylparameter5;p3_ylparameter6;p3_ylparameter7;p3_ylparameter8;
        p3_ylmode2label;p3_ylcheckbox11;p3_ylcheckbox12;p3_ylcheckbox13;p3_ylcheckbox14;p3_ylcheckbox15;p3_ylcheckbox16;p3_ylcheckbox17;p3_ylcheckbox18;
        p3_ylcolorlabel;p3_ylcolor1;p3_ylcolor2;p3_ylcolor3;p3_ylcolor4;p3_ylcolor5;p3_ylcolor6;p3_ylcolor7;p3_ylcolor8;
        p3_yllinestylelabel;p3_yllinestyle1;p3_yllinestyle2;p3_yllinestyle3;p3_yllinestyle4;p3_yllinestyle5;p3_yllinestyle6;p3_yllinestyle7;p3_yllinestyle8;
        p3_yllinewidthlabel;p3_yllinewidth1;p3_yllinewidth2;p3_yllinewidth3;p3_yllinewidth4;p3_yllinewidth5;p3_yllinewidth6;p3_yllinewidth7;p3_yllinewidth8;
        p3_ylmarkerlabel;p3_ylmarker1;p3_ylmarker2;p3_ylmarker3;p3_ylmarker4;p3_ylmarker5;p3_ylmarker6;p3_ylmarker7;p3_ylmarker8
        p3_ylmarkerfacecolorlabel;p3_ylmarkercolor1;p3_ylmarkercolor2;p3_ylmarkercolor3;p3_ylmarkercolor4;p3_ylmarkercolor5;p3_ylmarkercolor6;p3_ylmarkercolor7;p3_ylmarkercolor8;
        p3_ylmarkeredgecolorlabel;p3_ylmarkeredgecolor1;p3_ylmarkeredgecolor2;p3_ylmarkeredgecolor3;p3_ylmarkeredgecolor4;p3_ylmarkeredgecolor5;p3_ylmarkeredgecolor6;p3_ylmarkeredgecolor7;p3_ylmarkeredgecolor8;
        p3_ylmarkersizeabel;p3_ylmarkersize1;p3_ylmarkersize2;p3_ylmarkersize3;p3_ylmarkersize4;p3_ylmarkersize5;p3_ylmarkersize6;p3_ylmarkersize7;p3_ylmarkersize8;

        p3_yrmodelabel;p3_yrcheckbox1;p3_yrcheckbox2;p3_yrcheckbox3;p3_yrcheckbox4;p3_yrcheckbox5;p3_yrcheckbox6;p3_yrcheckbox7;p3_yrcheckbox8;
        p3_yrparameterlabel;
        p3_yrparameter1;p3_yrparameter2;p3_yrparameter3;p3_yrparameter4;p3_yrparameter5;p3_yrparameter6;p3_yrparameter7;p3_yrparameter8;
        p3_yrmode2label;p3_yrcheckbox11;p3_yrcheckbox12;p3_yrcheckbox13;p3_yrcheckbox14;p3_yrcheckbox15;p3_yrcheckbox16;p3_yrcheckbox17;p3_yrcheckbox18;
        p3_yrcolorlabel;p3_yrcolor1;p3_yrcolor2;p3_yrcolor3;p3_yrcolor4;p3_yrcolor5;p3_yrcolor6;p3_yrcolor7;p3_yrcolor8;
        p3_yrlinestylelabel;p3_yrlinestyle1;p3_yrlinestyle2;p3_yrlinestyle3;p3_yrlinestyle4;p3_yrlinestyle5;p3_yrlinestyle6;p3_yrlinestyle7;p3_yrlinestyle8;
        p3_yrlinewidthlabel;p3_yrlinewidth1;p3_yrlinewidth2;p3_yrlinewidth3;p3_yrlinewidth4;p3_yrlinewidth5;p3_yrlinewidth6;p3_yrlinewidth7;p3_yrlinewidth8;
        p3_yrmarkerlabel;p3_yrmarker1;p3_yrmarker2;p3_yrmarker3;p3_yrmarker4;p3_yrmarker5;p3_yrmarker6;p3_yrmarker7;p3_yrmarker8
        p3_yrmarkerfacecolorlabel;p3_yrmarkercolor1;p3_yrmarkercolor2;p3_yrmarkercolor3;p3_yrmarkercolor4;p3_yrmarkercolor5;p3_yrmarkercolor6;p3_yrmarkercolor7;p3_yrmarkercolor8;
        p3_yrmarkeredgecolorlabel;p3_yrmarkeredgecolor1;p3_yrmarkeredgecolor2;p3_yrmarkeredgecolor3;p3_yrmarkeredgecolor4;p3_yrmarkeredgecolor5;p3_yrmarkeredgecolor6;p3_yrmarkeredgecolor7;p3_yrmarkeredgecolor8;
        p3_yrmarkersizelabel;p3_yrmarkersize1;p3_yrmarkersize2;p3_yrmarkersize3;p3_yrmarkersize4;p3_yrmarkersize5;p3_yrmarkersize6;p3_yrmarkersize7;p3_yrmarkersize8;

        p3_UIFigure5;p3_Fileslectgridlayout;
        p3_file1enable;p3_file2enable;p3_file3enable;p3_file4enable;p3_file5enable;p3_file6enable;p3_file7enable;p3_file8enable;
        p3_File1Button;p3_File2Button;p3_File3Button;p3_File4Button;p3_File5Button;p3_File6Button;p3_File7Button;p3_File8Button;p3_File9Button
        p3_Filename1;p3_Filename2;p3_Filename3;p3_Filename4;p3_Filename5;p3_Filename6;p3_Filename7;p3_Filename8;
        p3_Filepath1;p3_Filepath2;p3_Filepath3;p3_Filepath4;p3_Filepath5;p3_Filepath6;p3_Filepath7;p3_Filepath8;
        p3_FilenameEditFieldLabel;p3_FilenameEditField_2Label;p3_FilenameEditField_3Label;p3_FilenameEditField_4Label;p3_FilenameEditField_5Label;p3_FilenameEditField_6Label;p3_FilenameEditField_7Label;p3_FilenameEditField_8Label;
        p3_file;p3_file_2;p3_path;p3_path_2;

    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p3f_uicreate(app);
            
            app.p3_scaling_struct.min=[0,0,0];
            app.p3_scaling_struct.max=[1,1,1];
            app.p3_scaling_struct.step=[0.1,0.1,0.1];
            app.p3_scaling_struct.grid=["off","off","off"];
            app.p3_scaling_struct.axislabel=["Xaxis","Yaxis(left)","Yaxis(right)"];
            app.p3_scaling_struct.color=["black","black","black"];
            app.p3_scaling_struct.mode=[0,0,0];
            app.p3_scaling_struct.logmode=[0,0,0];
            app.p3_axisnumber=1;
            app.p3_scaling_struct.stepenable=[1,1,1];

            app.p3_scalingmax.Value=1;
            app.p3_scalingstep.Value=0.1;
            app.p3_scalingaxislabel.Value="Xaxis";
            app.p3_scalingaxiscolor.Value='black';
            app.p3_scalingstep.Enable=0;

            app.p3_File1Button.Enable=0;
            app.p3_Filename1.Enable=0;
            app.p3_Filepath1.Enable=0;
            app.p3_File2Button.Enable=0;
            app.p3_Filename2.Enable=0;
            app.p3_Filepath2.Enable=0;
            app.p3_File3Button.Enable=0;
            app.p3_Filename3.Enable=0;
            app.p3_Filepath3.Enable=0;
            app.p3_File4Button.Enable=0;
            app.p3_Filename4.Enable=0;
            app.p3_Filepath4.Enable=0;
            app.p3_File5Button.Enable=0;
            app.p3_Filename5.Enable=0;
            app.p3_Filepath5.Enable=0;
            app.p3_File6Button.Enable=0;
            app.p3_Filename6.Enable=0;
            app.p3_Filepath6.Enable=0;
            app.p3_File7Button.Enable=0;
            app.p3_Filename7.Enable=0;
            app.p3_Filepath7.Enable=0;
            app.p3_File8Button.Enable=0;
            app.p3_Filename8.Enable=0;
            app.p3_Filepath8.Enable=0;
            app.p3_file=["","","","","","","",""];app.p3_file_2=["","","","","","","",""];
            app.p3_path=["","","","","","","",""];app.p3_path_2=["","","","","","","",""];
            app.p3_Message;

            %setting line width and marker color to 0.5 as default to avoid
            %error
            app.p3_yllinewidth1.Value=0.5;app.p3_yllinewidth2.Value=0.5;app.p3_yllinewidth3.Value=0.5;app.p3_yllinewidth4.Value=0.5;app.p3_yllinewidth5.Value=0.5;app.p3_yllinewidth6.Value=0.5;app.p3_yllinewidth7.Value=0.5;app.p3_yllinewidth8.Value=0.5;
            app.p3_yrlinewidth1.Value=0.5;app.p3_yrlinewidth2.Value=0.5;app.p3_yrlinewidth3.Value=0.5;app.p3_yrlinewidth4.Value=0.5;app.p3_yrlinewidth5.Value=0.5;app.p3_yrlinewidth6.Value=0.5;app.p3_yrlinewidth7.Value=0.5;app.p3_yrlinewidth8.Value=0.5;
            app.p3_ylmarkersize1.Value=0.5;app.p3_ylmarkersize2.Value=0.5;app.p3_ylmarkersize3.Value=0.5;app.p3_ylmarkersize4.Value=0.5;app.p3_ylmarkersize5.Value=0.5;app.p3_ylmarkersize6.Value=0.5;app.p3_ylmarkersize7.Value=0.5;app.p3_ylmarkersize8.Value=0.5;
            app.p3_yrmarkersize1.Value=0.5;app.p3_yrmarkersize2.Value=0.5;app.p3_yrmarkersize3.Value=0.5;app.p3_yrmarkersize4.Value=0.5;app.p3_yrmarkersize5.Value=0.5;app.p3_yrmarkersize6.Value=0.5;app.p3_yrmarkersize7.Value=0.5;app.p3_yrmarkersize8.Value=0.5;

            app.p3_Message.Text = "Initialisation complete";
        end

        % Button pushed function: p2_PlotButton
        function p2_PlotButton_2Pushed(app, event)
            p2f_plot(app);
        end

        % Value changed function: p2_VoltageSetValueDropDown
        function p2_VoltageSetValueDropDownValueChanged(app, event)
            p2f_Vdrpdwn(app);
        end

        % Value changed function: p2_SpeedSetValueDropDown
        function p2_SpeedSetValueDropDownValueChanged(app, event)
            p2f_Sdrpdwn(app);
        end

        % Value changed function: p2_t21Switch
        function p2_t21SwitchValueChanged(app, event)
            p2f_t21switch(app);
        end

        % Value changed function: p2_ManualScalingCheckBox
        function p2_ManualScalingCheckBoxValueChanged(app, event)
            p2f_scaling(app);   
        end

        % Button pushed function: p2_SelectFileButton
        function p2_SelectFileButtonPushed(app, event)
%             p2f_fileselected(app);
              app.p3_UIFigure5.Visible="on";
        end

        % Value changed function: p2_opt0
        function p2_opt0ValueChanged(app, event)
            value = app.p2_opt0.Value;
            if value==1                                     %if checkbox 1 is clicked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p1.Visible="on";                      %plot vibration1
                 else
                    app.p2_t99p5.Visible="on";                 %else plot temp1
                 end   
            else                                            %if checkbox 1 is unchecked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p1.Visible="off";                      %hide plot vibration1
                 else
                    app.p2_t99p5.Visible="off";                 %else hide plot temp1
                 end    
             end
            
        end

        % Value changed function: p2_opt1
        function p2_opt1ValueChanged(app, event)
            value = app.p2_opt1.Value;
            if value==1                                     %if checkbox 1 is clicked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p2.Visible="on";                      %plot vibration1
                 else
                    app.p2_t99p6.Visible="on";                 %else plot temp1
                 end   
            else                                            %if checkbox 1 is unchecked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p2.Visible="off";                      %hide plot vibration1
                 else
                    app.p2_t99p6.Visible="off";                 %else hide plot temp1
                 end    
             end
        end

        % Value changed function: p2_opt2
        function p2_opt2ValueChanged(app, event)
            value = app.p2_opt2.Value;
            if value==1                                     %if checkbox 1 is clicked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p3.Visible="on";                      %plot vibration1
                 else
                    app.p2_t99p7.Visible="on";                 %else plot temp1
                 end   
            else                                            %if checkbox 1 is unchecked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p3.Visible="off";                      %hide plot vibration1
                 else
                    app.p2_t99p7.Visible="off";                 %else hide plot temp1
                 end    
             end
        end

        % Value changed function: p2_opt3
        function p2_opt3ValueChanged(app, event)
            value = app.p2_opt3.Value;
            if value==1                                     %if checkbox 1 is clicked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p4.Visible="on";                      %plot vibration1
                 else
                    app.p2_t99p8.Visible="on";                 %else plot temp1
                 end   
            else                                            %if checkbox 1 is unchecked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p4.Visible="off";                      %hide plot vibration1
                 else
                    app.p2_t99p8.Visible="off";                 %else hide plot temp1
                 end    
             end
        end

        % Value changed function: p2_opt4
        function p2_opt4ValueChanged(app, event)
            value = app.p2_opt4.Value;
            if value==1                                     
                    app.p2_t99p9.Visible="on";                                   
            else                                           
                    app.p2_t99p9.Visible="off";                      
             end
        end

        % Value changed function: p2_opt5
        function p2_opt5ValueChanged(app, event)
            value = app.p2_opt5.Value;
            if value==1                                     
                    app.p2_t99p10.Visible="on";                                   
            else                                           
                    app.p2_t99p10.Visible="off";                      
             end
        end

        % Value changed function: p2_opt6
        function p2_opt6ValueChanged(app, event)
            value = app.p2_opt6.Value;
            if value==1                                     
                    app.p2_t99p11.Visible="on";                                   
            else                                           
                    app.p2_t99p11.Visible="off";                      
             end
        end

        % Value changed function: p2_opt7
        function p2_opt7ValueChanged(app, event)
            value = app.p2_opt7.Value;
            if value==1                                     
                    app.p2_t99p12.Visible="on";                                   
            else                                           
                    app.p2_t99p12.Visible="off";                      
             end
        end

        % Button pushed function: p3_customorderbutton
        function p3_p3_customorderbuttonPushed(app, event)
%              p3f_fileselected(app);
%              p3f_fileselect(app);
              app.p3_Message.Text = "Custom order selected, please select files";
              app.p3_UIFigure5.Visible="on";
        end

        % Value changed function: p3_scalingaxisname
        function p3_scalingaxisnameValueChanged(app, event)
            value = app.p3_scalingaxisname.Value;
            str=["Xaxis","Yaxis(left)","Yaxis(right)"];
            TF = contains(str,value);
            if TF(1) == 1
                m=1;
                app.p3_scalinglog.Enable='on';
                app.p3_scalinggrid.Enable='on';
            elseif TF(2) == 1
                m=2;
                app.p3_scalinglog.Enable='on';
                app.p3_scalinggrid.Enable='on';
            else
                m=3;
                app.p3_scalinglog.Enable='off';
                app.p3_scalinggrid.Enable='off';
            end
            app.p3_axisnumber=m;
            app.p3_scalingmin.Value=app.p3_scaling_struct.min(m);
            app.p3_scalingmax.Value=app.p3_scaling_struct.max(m);
            app.p3_scalingstep.Value=app.p3_scaling_struct.step(m);
            app.p3_scalinggrid.Value=app.p3_scaling_struct.grid(m);
            app.p3_scalingaxislabel.Value=app.p3_scaling_struct.axislabel(m);
            app.p3_scalingaxiscolor.Value=app.p3_scaling_struct.color(m);
            app.p3_scalingmode.Value=app.p3_scaling_struct.mode(m);
            app.p3_scalinglog.Value=app.p3_scaling_struct.logmode(m);
            app.p3_scalingstep.Enable=app.p3_scaling_struct.stepenable(m);
        end

        % Button pushed function: p3_plotbutton
        function p3_p3_plotbuttonPushed(app, event)
            p3f_plot(app);
        end

        % Value changed function: p3_scalingmin
        function p3_scalingminValueChanged(app, event)
            value = app.p3_scalingmin.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.min(m)=value;
        end

        % Value changed function: p3_scalingmax
        function p3_scalingmaxValueChanged(app, event)
            value = app.p3_scalingmax.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.max(m)=value;
        end

        % Value changed function: p3_scalingstep
        function p3_scalingstepValueChanged(app, event)
            value = app.p3_scalingstep.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.step(m)=value;
        end

        % Value changed function: p3_scalingaxislabel
        function p3_scalingaxislabelValueChanged(app, event)
            value = app.p3_scalingaxislabel.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.axislabel(m)=value;
        end

        % Value changed function: p3_scalingaxiscolor
        function p3_scalingaxiscolorValueChanged(app, event)
            value = app.p3_scalingaxiscolor.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.color(m)=value;
        end

        % Value changed function: p3_scalingmode
        function p3_scalingmodeValueChanged(app, event)
            value = app.p3_scalingmode.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.mode(m)=value;

            app.p3_scaling_struct.min(m)=app.p3_scalingmin.Value;
            app.p3_scaling_struct.max(m)=app.p3_scalingmax.Value;
            app.p3_scaling_struct.step(m)=app.p3_scalingstep.Value;
            app.p3_scaling_struct.grid(m)=app.p3_scalinggrid.Value;
            app.p3_scaling_struct.axislabel(m)=app.p3_scalingaxislabel.Value;
            app.p3_scaling_struct.color(m)=app.p3_scalingaxiscolor.Value;
            app.p3_scaling_struct.mode(m)=app.p3_scalingmode.Value; 

        end

        % Value changed function: p3_scalinggrid
        function p3_scalinggridValueChanged(app, event)
            value = app.p3_scalinggrid.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.grid(m)=value;

            if app.p3_axisnumber == 2
                 app.p3_scaling_struct.grid(3)=value;
            elseif app.p3_axisnumber == 3
                 app.p3_scaling_struct.grid(2)=value;
            end    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            p3f_mainclosereq(app);
            delete(app);
        end

        % Value changed function: p3_scalinglog
        function p3_scalinglogValueChanged(app, event)
            value = app.p3_scalinglog.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.logmode(m)=value;
            app.p3_scaling_struct.stepenable(m)=~value;
               
            if value == 1
                app.p3_scalingstep.Enable=0;
                if m==2
                app.p3_ylcheckbox11.Enable=0;
                app.p3_ylcheckbox12.Enable=0;
                app.p3_ylcheckbox13.Enable=0;
                app.p3_ylcheckbox14.Enable=0;
                app.p3_ylcheckbox15.Enable=0;
                app.p3_ylcheckbox16.Enable=0;
                app.p3_ylcheckbox17.Enable=0;
                app.p3_ylcheckbox18.Enable=0;

                app.p3_ylcheckbox11.Value=0;
                app.p3_ylcheckbox12.Value=0;
                app.p3_ylcheckbox13.Value=0;
                app.p3_ylcheckbox14.Value=0;
                app.p3_ylcheckbox15.Value=0;
                app.p3_ylcheckbox16.Value=0;
                app.p3_ylcheckbox17.Value=0;
                app.p3_ylcheckbox18.Value=0;

                app.p3_yrcheckbox11.Enable=0;
                app.p3_yrcheckbox12.Enable=0;
                app.p3_yrcheckbox13.Enable=0;
                app.p3_yrcheckbox14.Enable=0;
                app.p3_yrcheckbox15.Enable=0;
                app.p3_yrcheckbox16.Enable=0;
                app.p3_yrcheckbox17.Enable=0;
                app.p3_yrcheckbox18.Enable=0;

                app.p3_yrcheckbox11.Value=0;
                app.p3_yrcheckbox12.Value=0;
                app.p3_yrcheckbox13.Value=0;
                app.p3_yrcheckbox14.Value=0;
                app.p3_yrcheckbox15.Value=0;
                app.p3_yrcheckbox16.Value=0;
                app.p3_yrcheckbox17.Value=0;
                app.p3_yrcheckbox18.Value=0;

                app.p3_yrcheckbox1.Enable=0;
                app.p3_yrcheckbox2.Enable=0;
                app.p3_yrcheckbox3.Enable=0;
                app.p3_yrcheckbox4.Enable=0;
                app.p3_yrcheckbox5.Enable=0;
                app.p3_yrcheckbox6.Enable=0;
                app.p3_yrcheckbox7.Enable=0;
                app.p3_yrcheckbox8.Enable=0;

                app.p3_yrcheckbox1.Value=0;
                app.p3_yrcheckbox2.Value=0;
                app.p3_yrcheckbox3.Value=0;
                app.p3_yrcheckbox4.Value=0;
                app.p3_yrcheckbox5.Value=0;
                app.p3_yrcheckbox6.Value=0;
                app.p3_yrcheckbox7.Value=0;
                app.p3_yrcheckbox8.Value=0;
                
                end  
            else
                app.p3_scalingstep.Enable=1;
                if m==2
                app.p3_yrcheckbox1.Enable=1;
                app.p3_yrcheckbox2.Enable=1;
                app.p3_yrcheckbox3.Enable=1;
                app.p3_yrcheckbox4.Enable=1;
                app.p3_yrcheckbox5.Enable=1;
                app.p3_yrcheckbox6.Enable=1;
                app.p3_yrcheckbox7.Enable=1;
                app.p3_yrcheckbox8.Enable=1;
                app.p3_yrcheckbox11.Enable=1;
                app.p3_yrcheckbox12.Enable=1;
                app.p3_yrcheckbox13.Enable=1;
                app.p3_yrcheckbox14.Enable=1;
                app.p3_yrcheckbox15.Enable=1;
                app.p3_yrcheckbox16.Enable=1;
                app.p3_yrcheckbox17.Enable=1;
                app.p3_yrcheckbox18.Enable=1;
                end 
            end
        end

        % Menu selected function: XaxisMenu
        function XaxisMenuSelected(app, event)
            app.p3_UIFigure2.Visible="on";
            app.p3_Message.Text = "XaxisMenuSelected";
        end

        % Menu selected function: YaxisleftMenu
        function YaxisleftMenuSelected(app, event)
            app.p3_UIFigure3.Visible="on";
            app.p3_Message.Text = "YaxisleftMenuSelected";
        end

        % Menu selected function: YaxisrightMenu
        function YaxisrightMenuSelected(app, event)
            app.p3_UIFigure4.Visible="on";
            app.p3_Message.Text = "YaxisrightMenuSelected";
        end

        % Button pushed function: p3_defaultorderbutton
        function p3_defaultorderbuttonPushed(app, event)
            app.p3_Message.Text = "Default order selected, please select files";
            p3f_defaultorder(app);
        end

        % Menu selected function: HideMenu
        function HideMenuSelected(app, event)
            app.p3_filegridlayout.Visible = "off";
            app.p3_ManualscalingPanel.Visible = "off";
            app.p3_plotbutton.Visible = "off";
            app.p3_UIAxes.Layout.Row = [1 9];
            app.p3_UIAxes.Layout.Column = [1 6];
        end

        % Menu selected function: ShowMenu
        function ShowMenuSelected(app, event)
            app.p3_filegridlayout.Visible = "on";
            app.p3_ManualscalingPanel.Visible = "on";
            app.p3_plotbutton.Visible = "on";
            app.p3_UIAxes.Layout.Row = [1 9];
            app.p3_UIAxes.Layout.Column = [1 5];
        end

        % Button pushed function: p3_hidecontrol
        function p3_hidecontrolPushed(app, event)
            mode=app.p3_hidecontrol.Text(1);
            disp(mode);
            if mode== ">"
                app.p3_filegridlayout.Visible = "off";
                app.p3_ManualscalingPanel.Visible = "off";
                app.p3_plotbutton.Visible = "off";
                app.p3_UIAxes.Layout.Row = [1 9];
                app.p3_UIAxes.Layout.Column = [1 7];
                app.p3_hidecontrol.Layout.Row = [5 7];
                app.p3_hidecontrol.Layout.Column = 8;
                app.p3_hidecontrol.Text="<";
            else
                app.p3_filegridlayout.Visible = "on";
                app.p3_ManualscalingPanel.Visible = "on";
                app.p3_plotbutton.Visible = "on";
                app.p3_UIAxes.Layout.Row = [1 9];
                app.p3_UIAxes.Layout.Column = [1 5];
                app.p3_hidecontrol.Layout.Row = [5 7];
                app.p3_hidecontrol.Layout.Column = 6;
                app.p3_hidecontrol.Text=">";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.p2_FreePlotTab = uitab(app.TabGroup);
            app.p2_FreePlotTab.Title = 'FreePlot';
            app.GridLayout9 = uigridlayout(app.p2_FreePlotTab);
            app.p3_UIAxes = uiaxes(app.GridLayout9);
            xlabel(app.p3_UIAxes, 'X')
            ylabel(app.p3_UIAxes, 'Y')
            zlabel(app.p3_UIAxes, 'Z')
            app.p3_ManualscalingPanel = uipanel(app.GridLayout9);
            app.p3_ManualscalingPanel.Title = '               Manual scaling';
            app.GridLayout8 = uigridlayout(app.p3_ManualscalingPanel);
            app.p3_scalingaxisnamelabel = uilabel(app.GridLayout8);
            app.p3_scalingaxisnamelabel.Text = 'Axis';
            app.p3_scalingmin = uieditfield(app.GridLayout8, 'numeric');
            app.p3_scalingmin.ValueChangedFcn = createCallbackFcn(app, @p3_scalingminValueChanged, true);
            app.p3_scalingmin.Tooltip = {'Min liimit of the corresponding axis''s scale'};
            app.p3_scalingmax = uieditfield(app.GridLayout8, 'numeric');
            app.p3_scalingmax.ValueChangedFcn = createCallbackFcn(app, @p3_scalingmaxValueChanged, true);
            app.p3_scalingmax.Tooltip = {'Max liimit of the corresponding axis''s scale'};
            app.p3_scalingstep = uieditfield(app.GridLayout8, 'numeric');
            app.p3_scalingstep.ValueChangedFcn = createCallbackFcn(app, @p3_scalingstepValueChanged, true);
            app.p3_scalingstep.Tooltip = {'Step width for the corresponding axis'};
            app.p3_scalingminlabel = uilabel(app.GridLayout8);
            app.p3_scalingminlabel.Text = 'min';
            app.p3_scalingmaxlabel = uilabel(app.GridLayout8);
            app.p3_scalingmaxlabel.Text = 'max';
            app.p3_scalingmsteplabel = uilabel(app.GridLayout8);
            app.p3_scalingmsteplabel.Text = 'step';
            app.p3_scalingmode = uicheckbox(app.GridLayout8);
            app.p3_scalingmode.ValueChangedFcn = createCallbackFcn(app, @p3_scalingmodeValueChanged, true);
            app.p3_scalingmode.Tooltip = {'If selected, all the above options will be set else they will be set automatically.'};
            app.p3_scalingmode.Text = '';
            app.p3_scalingmodelabel = uilabel(app.GridLayout8);
            app.p3_scalingmodelabel.Text = 'Manual scaling';
            app.p3_scalingaxisname = uidropdown(app.GridLayout8);
            app.p3_scalingaxisname.Items = {'Xaxis', 'Yaxis(left)', 'Yaxis(right)', ''};
            app.p3_scalingaxisname.ValueChangedFcn = createCallbackFcn(app, @p3_scalingaxisnameValueChanged, true);
            app.p3_scalingaxisname.Tooltip = {'To change settings for each axis, change the axis from this dropdown.'};
            app.p3_scalingaxisname.Value = 'Xaxis';
            app.p3_scalingaxislabellabel = uilabel(app.GridLayout8);
            app.p3_scalingaxislabellabel.Text = 'Axis label';
            app.p3_scalingaxislabel = uieditfield(app.GridLayout8, 'text');
            app.p3_scalingaxislabel.ValueChangedFcn = createCallbackFcn(app, @p3_scalingaxislabelValueChanged, true);
            app.p3_scalingaxislabel.Tooltip = {'Label for the corresponding axis'};
            app.p3_scalingcolorlabel = uilabel(app.GridLayout8);
            app.p3_scalingcolorlabel.Text = 'Axis color';
            app.p3_scalingaxiscolor = uidropdown(app.GridLayout8);
            app.p3_scalingaxiscolor.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white', ''};
            app.p3_scalingaxiscolor.ValueChangedFcn = createCallbackFcn(app, @p3_scalingaxiscolorValueChanged, true);
            app.p3_scalingaxiscolor.Tooltip = {'Color of the corresponding axis'};
            app.p3_scalingaxiscolor.Value = 'red';
            app.p3_scalingloglabel = uilabel(app.GridLayout8);
            app.p3_scalingloglabel.Text = 'Log scaling';
            app.p3_scalinglog = uicheckbox(app.GridLayout8);
            app.p3_scalinglog.ValueChangedFcn = createCallbackFcn(app, @p3_scalinglogValueChanged, true);
            app.p3_scalinglog.Tooltip = {'If selected corresponding axis''s scale will be log scale(base 10).'};
            app.p3_scalinglog.Text = '';
            app.p3_scalingaxisgridlabel = uilabel(app.GridLayout8);
            app.p3_scalingaxisgridlabel.Text = 'Axis grid';
            app.p3_scalinggrid = uidropdown(app.GridLayout8);
            app.p3_scalinggrid.Items = {'off', 'on', ''};
            app.p3_scalinggrid.ValueChangedFcn = createCallbackFcn(app, @p3_scalinggridValueChanged, true);
            app.p3_scalinggrid.Tooltip = {'If value is changed for one of the Yaxes, the other value will also be changed(Since only one Y axis grid is possible).'};
            app.p3_scalinggrid.Value = 'off';
            app.p3_plotbutton = uibutton(app.GridLayout9, 'push');
            app.p3_plotbutton.ButtonPushedFcn = createCallbackFcn(app, @p3_p3_plotbuttonPushed, true);
            app.p3_plotbutton.Tooltip = {'Pushing this plots the graph for selected data with selected options'};
            app.p3_plotbutton.Text = 'Plot';
            app.p3_filegridlayout = uigridlayout(app.GridLayout9);
            app.p3_customorderbutton = uibutton(app.p3_filegridlayout, 'push');
            app.p3_customorderbutton.ButtonPushedFcn = createCallbackFcn(app, @p3_p3_customorderbuttonPushed, true);
            app.p3_customorderbutton.Tooltip = {'Select a file with eaither .xlsx or .csv extension.'};
            app.p3_customorderbutton.Text = 'Custom order';
            app.p3_defaultorderbutton = uibutton(app.p3_filegridlayout, 'push');
            app.p3_defaultorderbutton.ButtonPushedFcn = createCallbackFcn(app, @p3_defaultorderbuttonPushed, true);
            app.p3_defaultorderbutton.Text = 'Default order';
            app.p3_hidecontrol = uibutton(app.GridLayout9, 'push');
            app.p3_hidecontrol.ButtonPushedFcn = createCallbackFcn(app, @p3_hidecontrolPushed, true);
            app.p3_hidecontrol.Text = {'>'; ''};
            app.p2_ResultTab = uitab(app.TabGroup);
            app.p2_ResultTab.Title = 'Inspection_Items';
            app.p2_UIAxes = uiaxes(app.p2_ResultTab);
            xlabel(app.p2_UIAxes, 'X')
            ylabel(app.p2_UIAxes, 'Y')
            zlabel(app.p2_UIAxes, 'Z')
            app.p2_UIAxes2 = uiaxes(app.p2_ResultTab);
            xlabel(app.p2_UIAxes2, 'X')
            ylabel(app.p2_UIAxes2, 'Y')
            zlabel(app.p2_UIAxes2, 'Z')
            app.p2_UIAxes2.Visible = 'off';
            app.p2_UIAxes3 = uiaxes(app.p2_ResultTab);
            xlabel(app.p2_UIAxes3, 'X')
            ylabel(app.p2_UIAxes3, 'Y')
            zlabel(app.p2_UIAxes3, 'Z')
            app.p2_UIAxes3.Visible = 'off';
            app.p2_UIAxes4 = uiaxes(app.p2_ResultTab);
            xlabel(app.p2_UIAxes4, 'X')
            ylabel(app.p2_UIAxes4, 'Y')
            zlabel(app.p2_UIAxes4, 'Z')
            app.p2_UIAxes4.Visible = 'off';
            app.p2_PlotButton = uibutton(app.p2_ResultTab, 'push');
            app.p2_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @p2_PlotButton_2Pushed, true);
            app.p2_PlotButton.Text = 'Plot';
            app.XminEditFieldLabel = uilabel(app.p2_ResultTab);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.p2_XminEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.p2_ResultTab);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.p2_XmaxEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.YminleftEditFieldLabel = uilabel(app.p2_ResultTab);
            app.YminleftEditFieldLabel.Text = 'Ymin(left)';
            app.p2_YminleftEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.YmaxleftEditFieldLabel = uilabel(app.p2_ResultTab);
            app.YmaxleftEditFieldLabel.Text = 'Ymax(left)';
            app.p2_YmaxleftEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.YminrightEditFieldLabel = uilabel(app.p2_ResultTab);
            app.YminrightEditFieldLabel.Text = 'Ymin(right)';
            app.p2_YminrightEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.YmaxrightEditField_2Label = uilabel(app.p2_ResultTab);
            app.YmaxrightEditField_2Label.Text = 'Ymax(right)';
            app.p2_YmaxrightEditField_2 = uieditfield(app.p2_ResultTab, 'numeric');
            app.p2_ManualScalingCheckBox = uicheckbox(app.p2_ResultTab);
            app.p2_ManualScalingCheckBox.ValueChangedFcn = createCallbackFcn(app, @p2_ManualScalingCheckBoxValueChanged, true);
            app.p2_ManualScalingCheckBox.Text = 'ManualScaling';
            app.TorqueSetValueDropDownLabel = uilabel(app.p2_ResultTab);
            app.TorqueSetValueDropDownLabel.Visible = 'off';
            app.TorqueSetValueDropDownLabel.Text = 'Torque Set Value';
            app.p2_TorqueSetValueDropDown = uidropdown(app.p2_ResultTab);
            app.p2_TorqueSetValueDropDown.Visible = 'off';
            app.VoltageSetValueDropDownLabel = uilabel(app.p2_ResultTab);
            app.VoltageSetValueDropDownLabel.Visible = 'off';
            app.VoltageSetValueDropDownLabel.Text = 'Voltage Set Value';
            app.p2_VoltageSetValueDropDown = uidropdown(app.p2_ResultTab);
            app.p2_VoltageSetValueDropDown.ValueChangedFcn = createCallbackFcn(app, @p2_VoltageSetValueDropDownValueChanged, true);
            app.p2_VoltageSetValueDropDown.Visible = 'off';
            app.p2_ButtonGroup_2 = uibuttongroup(app.p2_ResultTab);
            app.p2_ButtonGroup_2.Enable = 'off';
            app.p2_ButtonGroup_2.Visible = 'off';
            app.p2_T40ch1Button = uiradiobutton(app.p2_ButtonGroup_2);
            app.p2_T40ch1Button.Text = 'T40 ch1';
            app.p2_T40ch1Button.Value = true;
            app.p2_T40ch2Button = uiradiobutton(app.p2_ButtonGroup_2);
            app.p2_T40ch2Button.Text = 'T40 ch2';
            app.SpeedSetValueDropDownLabel = uilabel(app.p2_ResultTab);
            app.SpeedSetValueDropDownLabel.Visible = 'off';
            app.SpeedSetValueDropDownLabel.Text = 'Speed Set Value';
            app.p2_SpeedSetValueDropDown = uidropdown(app.p2_ResultTab);
            app.p2_SpeedSetValueDropDown.ValueChangedFcn = createCallbackFcn(app, @p2_SpeedSetValueDropDownValueChanged, true);
            app.p2_SpeedSetValueDropDown.Visible = 'off';
            app.StepEditFieldLabel = uilabel(app.p2_ResultTab);
            app.StepEditFieldLabel.Visible = 'off';
            app.StepEditFieldLabel.Text = 'Step';
            app.p2_StepEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.p2_StepEditField.Visible = 'off';
            app.p2_TableLabel = uilabel(app.p2_ResultTab);
            app.p2_TableLabel.Text = 'Table';
            app.p2_Label = uilabel(app.p2_ResultTab);
            app.p2_GraphLabel = uilabel(app.p2_ResultTab);
            app.p2_GraphLabel.Text = 'Graph';
            app.p2_voltagecbx = uicheckbox(app.p2_ResultTab);
            app.p2_voltagecbx.Enable = 'off';
            app.p2_voltagecbx.Visible = 'off';
            app.p2_voltagecbx.Text = '';
            app.p2_speedcbx = uicheckbox(app.p2_ResultTab);
            app.p2_speedcbx.Enable = 'off';
            app.p2_speedcbx.Visible = 'off';
            app.p2_speedcbx.Text = '';
            app.p2_torquecbx = uicheckbox(app.p2_ResultTab);
            app.p2_torquecbx.Enable = 'off';
            app.p2_torquecbx.Visible = 'off';
            app.p2_torquecbx.Text = '';
            app.p2_t21Switch = uiswitch(app.p2_ResultTab, 'slider');
            app.p2_t21Switch.Items = {'T-Angle', 'Mrate-Angle'};
            app.p2_t21Switch.ValueChangedFcn = createCallbackFcn(app, @p2_t21SwitchValueChanged, true);
            app.p2_t21Switch.Visible = 'off';
            app.p2_t21Switch.Value = 'T-Angle';
            app.p2_UITable2 = uitable(app.p2_ResultTab);
            app.p2_UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.p2_UITable2.RowName = {};
            app.p2_SelectFileButton = uibutton(app.p2_ResultTab, 'push');
            app.p2_SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @p2_SelectFileButtonPushed, true);
            app.p2_SelectFileButton.Text = 'Select File';
            app.p2_t99_Switch = uiswitch(app.p2_ResultTab, 'slider');
            app.p2_t99_Switch.Items = {'Vibration', 'Temperature'};
            app.p2_t99_Switch.Visible = 'off';
            app.p2_t99_Switch.Value = 'Vibration';
            app.p2_opt0 = uicheckbox(app.p2_ResultTab);
            app.p2_opt0.ValueChangedFcn = createCallbackFcn(app, @p2_opt0ValueChanged, true);
            app.p2_opt0.Enable = 'off';
            app.p2_opt0.Visible = 'off';
            app.p2_opt0.Text = '1';
            app.p2_opt1 = uicheckbox(app.p2_ResultTab);
            app.p2_opt1.ValueChangedFcn = createCallbackFcn(app, @p2_opt1ValueChanged, true);
            app.p2_opt1.Enable = 'off';
            app.p2_opt1.Visible = 'off';
            app.p2_opt1.Text = '2';
            app.p2_opt2 = uicheckbox(app.p2_ResultTab);
            app.p2_opt2.ValueChangedFcn = createCallbackFcn(app, @p2_opt2ValueChanged, true);
            app.p2_opt2.Enable = 'off';
            app.p2_opt2.Visible = 'off';
            app.p2_opt2.Text = '3';
            app.p2_opt3 = uicheckbox(app.p2_ResultTab);
            app.p2_opt3.ValueChangedFcn = createCallbackFcn(app, @p2_opt3ValueChanged, true);
            app.p2_opt3.Enable = 'off';
            app.p2_opt3.Visible = 'off';
            app.p2_opt3.Text = '4';
            app.p2_opt4 = uicheckbox(app.p2_ResultTab);
            app.p2_opt4.ValueChangedFcn = createCallbackFcn(app, @p2_opt4ValueChanged, true);
            app.p2_opt4.Enable = 'off';
            app.p2_opt4.Visible = 'off';
            app.p2_opt4.Text = '5';
            app.p2_opt5 = uicheckbox(app.p2_ResultTab);
            app.p2_opt5.ValueChangedFcn = createCallbackFcn(app, @p2_opt5ValueChanged, true);
            app.p2_opt5.Enable = 'off';
            app.p2_opt5.Visible = 'off';
            app.p2_opt5.Text = '6';
            app.p2_opt6 = uicheckbox(app.p2_ResultTab);
            app.p2_opt6.ValueChangedFcn = createCallbackFcn(app, @p2_opt6ValueChanged, true);
            app.p2_opt6.Enable = 'off';
            app.p2_opt6.Visible = 'off';
            app.p2_opt6.Text = '7';
            app.p2_opt7 = uicheckbox(app.p2_ResultTab);
            app.p2_opt7.ValueChangedFcn = createCallbackFcn(app, @p2_opt7ValueChanged, true);
            app.p2_opt7.Enable = 'off';
            app.p2_opt7.Visible = 'off';
            app.p2_opt7.Text = '8';
            app.p3_Message = uilabel(app.Panel);
            app.p3_Message.Text = '';
            app.p3_MessageLabel = uilabel(app.UIFigure);
            app.p3_MessageLabel.Text = 'Message: ';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.HideMenu = uimenu(app.Menu);
            app.HideMenu.MenuSelectedFcn = createCallbackFcn(app, @HideMenuSelected, true);
            app.HideMenu.Text = 'Hide';
            app.ShowMenu = uimenu(app.Menu);
            app.ShowMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowMenuSelected, true);
            app.ShowMenu.Text = 'Show';
            app.XaxisMenu = uimenu(app.ContextMenu);
            app.XaxisMenu.MenuSelectedFcn = createCallbackFcn(app, @XaxisMenuSelected, true);
            app.XaxisMenu.Text = 'Xaxis';
            app.YaxisleftMenu = uimenu(app.ContextMenu);
            app.YaxisleftMenu.MenuSelectedFcn = createCallbackFcn(app, @YaxisleftMenuSelected, true);
            app.YaxisleftMenu.Text = 'Yaxis(left)';
            app.YaxisrightMenu = uimenu(app.ContextMenu);
            app.YaxisrightMenu.MenuSelectedFcn = createCallbackFcn(app, @YaxisrightMenuSelected, true);
            app.YaxisrightMenu.Text = 'Yaxis(right)';
            % Assign app.ContextMenu
            app.p3_UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p2_StandaloneApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end