classdef p2_StandaloneApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        p2_ResultTab                  matlab.ui.container.Tab
        p2_opt7                       matlab.ui.control.CheckBox
        p2_opt6                       matlab.ui.control.CheckBox
        p2_opt5                       matlab.ui.control.CheckBox
        p2_opt4                       matlab.ui.control.CheckBox
        p2_opt3                       matlab.ui.control.CheckBox
        p2_opt2                       matlab.ui.control.CheckBox
        p2_opt1                       matlab.ui.control.CheckBox
        p2_opt0                       matlab.ui.control.CheckBox
        p2_t99_Switch                 matlab.ui.control.Switch
        p2_SelectFileButton           matlab.ui.control.Button
        p2_UITable2                   matlab.ui.control.Table
        p2_t21Switch                  matlab.ui.control.Switch
        p2_torquecbx                  matlab.ui.control.CheckBox
        p2_speedcbx                   matlab.ui.control.CheckBox
        p2_voltagecbx                 matlab.ui.control.CheckBox
        p2_GraphLabel                 matlab.ui.control.Label
        p2_Label                      matlab.ui.control.Label
        p2_TableLabel                 matlab.ui.control.Label
        p2_StepEditField              matlab.ui.control.NumericEditField
        StepEditFieldLabel            matlab.ui.control.Label
        p2_SpeedSetValueDropDown      matlab.ui.control.DropDown
        SpeedSetValueDropDownLabel    matlab.ui.control.Label
        p2_ButtonGroup_2              matlab.ui.container.ButtonGroup
        p2_T40ch2Button               matlab.ui.control.RadioButton
        p2_T40ch1Button               matlab.ui.control.RadioButton
        p2_VoltageSetValueDropDown    matlab.ui.control.DropDown
        VoltageSetValueDropDownLabel  matlab.ui.control.Label
        p2_TorqueSetValueDropDown     matlab.ui.control.DropDown
        TorqueSetValueDropDownLabel   matlab.ui.control.Label
        p2_ManualScalingCheckBox      matlab.ui.control.CheckBox
        p2_YmaxrightEditField_2       matlab.ui.control.NumericEditField
        YmaxrightEditField_2Label     matlab.ui.control.Label
        p2_YminrightEditField         matlab.ui.control.NumericEditField
        YminrightEditFieldLabel       matlab.ui.control.Label
        p2_YmaxleftEditField          matlab.ui.control.NumericEditField
        YmaxleftEditFieldLabel        matlab.ui.control.Label
        p2_YminleftEditField          matlab.ui.control.NumericEditField
        YminleftEditFieldLabel        matlab.ui.control.Label
        p2_XmaxEditField              matlab.ui.control.NumericEditField
        XmaxEditFieldLabel            matlab.ui.control.Label
        p2_XminEditField              matlab.ui.control.NumericEditField
        XminEditFieldLabel            matlab.ui.control.Label
        p2_PlotButton                 matlab.ui.control.Button
        p2_UIAxes4                    matlab.ui.control.UIAxes
        p2_UIAxes3                    matlab.ui.control.UIAxes
        p2_UIAxes2                    matlab.ui.control.UIAxes
        p2_UIAxes                     matlab.ui.control.UIAxes
        p2_FreePlotTab                matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        GraphTab                      matlab.ui.container.Tab
        GridLayout7                   matlab.ui.container.GridLayout
        SelectFileButton              matlab.ui.control.Button
        ManualscalingPanel            matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        p3_scalinggrid                matlab.ui.control.DropDown
        p3_scalingaxisgridlabel       matlab.ui.control.Label
        p3_scalingaxiscolor           matlab.ui.control.DropDown
        p3_scalingcolorlabel          matlab.ui.control.Label
        p3_scalingaxislabel           matlab.ui.control.EditField
        p3_scalingaxislabellabel      matlab.ui.control.Label
        p3_scalingaxisname            matlab.ui.control.DropDown
        p3_scalingmodelabel           matlab.ui.control.Label
        p3_scalingmode                matlab.ui.control.CheckBox
        p3_scalingmsteplabel          matlab.ui.control.Label
        p3_scalingmaxlabel            matlab.ui.control.Label
        p3_scalingminlabel            matlab.ui.control.Label
        p3_scalingstep                matlab.ui.control.NumericEditField
        p3_scalingmax                 matlab.ui.control.NumericEditField
        p3_scalingmin                 matlab.ui.control.NumericEditField
        p3_scalingaxisnamelabel       matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        p3_UIAxes                     matlab.ui.control.UIAxes
        XaxisTab_2                    matlab.ui.container.Tab
        GridLayout2_3                 matlab.ui.container.GridLayout
        p3_xparameter1                matlab.ui.control.DropDown
        p3_xparameterlabel            matlab.ui.control.Label
        YaxisLeftTab                  matlab.ui.container.Tab
        GridLayout2_5                 matlab.ui.container.GridLayout
        p3_ylmarkeredgecolor4         matlab.ui.control.DropDown
        p3_ylmarkeredgecolor8         matlab.ui.control.DropDown
        p3_ylmarkeredgecolor3         matlab.ui.control.DropDown
        p3_ylmarkeredgecolor7         matlab.ui.control.DropDown
        p3_ylmarkeredgecolor2         matlab.ui.control.DropDown
        p3_ylmarkeredgecolor6         matlab.ui.control.DropDown
        p3_ylmarkeredgecolor1         matlab.ui.control.DropDown
        p3_ylmarkeredgecolor5         matlab.ui.control.DropDown
        p3_ylmarkeredgecolorlabel     matlab.ui.control.Label
        p3_ylmode2label               matlab.ui.control.Label
        p3_ylmodelabel                matlab.ui.control.Label
        p3_ylcheckbox18               matlab.ui.control.CheckBox
        p3_ylcheckbox17               matlab.ui.control.CheckBox
        p3_ylcheckbox16               matlab.ui.control.CheckBox
        p3_ylcheckbox15               matlab.ui.control.CheckBox
        p3_ylcheckbox14               matlab.ui.control.CheckBox
        p3_ylcheckbox13               matlab.ui.control.CheckBox
        p3_ylcheckbox12               matlab.ui.control.CheckBox
        p3_ylcheckbox11               matlab.ui.control.CheckBox
        p3_ylcheckbox8                matlab.ui.control.CheckBox
        p3_ylcheckbox7                matlab.ui.control.CheckBox
        p3_ylcheckbox6                matlab.ui.control.CheckBox
        p3_ylcheckbox5                matlab.ui.control.CheckBox
        p3_ylcheckbox4                matlab.ui.control.CheckBox
        p3_ylcheckbox3                matlab.ui.control.CheckBox
        p3_ylcheckbox2                matlab.ui.control.CheckBox
        p3_ylcheckbox1                matlab.ui.control.CheckBox
        p3_ylmarkercolor8             matlab.ui.control.DropDown
        p3_ylmarker7                  matlab.ui.control.DropDown
        p3_ylmarker8                  matlab.ui.control.DropDown
        p3_ylmarkercolor7             matlab.ui.control.DropDown
        p3_ylmarkercolor5             matlab.ui.control.DropDown
        p3_ylmarker5                  matlab.ui.control.DropDown
        p3_ylmarkercolor6             matlab.ui.control.DropDown
        p3_ylmarker6                  matlab.ui.control.DropDown
        p3_ylmarkercolor4             matlab.ui.control.DropDown
        p3_ylmarker4                  matlab.ui.control.DropDown
        p3_ylmarkercolor3             matlab.ui.control.DropDown
        p3_ylmarker3                  matlab.ui.control.DropDown
        p3_ylmarkersize6              matlab.ui.control.NumericEditField
        p3_ylmarkersize5              matlab.ui.control.NumericEditField
        p3_ylmarkersize8              matlab.ui.control.NumericEditField
        p3_ylmarkersize7              matlab.ui.control.NumericEditField
        p3_yllinewidth6               matlab.ui.control.NumericEditField
        p3_ylmarkersize4              matlab.ui.control.NumericEditField
        p3_ylmarkersize3              matlab.ui.control.NumericEditField
        p3_yllinewidth8               matlab.ui.control.NumericEditField
        p3_yllinewidth7               matlab.ui.control.NumericEditField
        p3_yllinewidth4               matlab.ui.control.NumericEditField
        p3_yllinewidth5               matlab.ui.control.NumericEditField
        p3_yllinewidth3               matlab.ui.control.NumericEditField
        p3_yllinestyle8               matlab.ui.control.DropDown
        p3_yllinestyle7               matlab.ui.control.DropDown
        p3_yllinestyle6               matlab.ui.control.DropDown
        p3_yllinestyle5               matlab.ui.control.DropDown
        p3_yllinestyle4               matlab.ui.control.DropDown
        p3_yllinestyle3               matlab.ui.control.DropDown
        p3_ylcolor8                   matlab.ui.control.DropDown
        p3_ylcolor7                   matlab.ui.control.DropDown
        p3_ylcolor6                   matlab.ui.control.DropDown
        p3_ylcolor5                   matlab.ui.control.DropDown
        p3_ylcolor4                   matlab.ui.control.DropDown
        p3_ylcolor3                   matlab.ui.control.DropDown
        p3_ylparameter8               matlab.ui.control.DropDown
        p3_ylparameter7               matlab.ui.control.DropDown
        p3_ylparameter6               matlab.ui.control.DropDown
        p3_ylparameter5               matlab.ui.control.DropDown
        p3_ylparameter4               matlab.ui.control.DropDown
        p3_ylparameter3               matlab.ui.control.DropDown
        p3_ylmarkercolor2             matlab.ui.control.DropDown
        p3_ylmarker2                  matlab.ui.control.DropDown
        p3_ylmarkercolor1             matlab.ui.control.DropDown
        p3_ylmarker1                  matlab.ui.control.DropDown
        p3_yllinewidth2               matlab.ui.control.NumericEditField
        p3_ylmarkersize2              matlab.ui.control.NumericEditField
        p3_ylmarkersize1              matlab.ui.control.NumericEditField
        p3_yllinewidth1               matlab.ui.control.NumericEditField
        p3_ylmarkersizeabel           matlab.ui.control.Label
        p3_ylmarkerfacecolorlabel     matlab.ui.control.Label
        p3_yllinewidthlabel           matlab.ui.control.Label
        p3_ylmarkerlabel              matlab.ui.control.Label
        p3_yllinestyle2               matlab.ui.control.DropDown
        p3_yllinestyle1               matlab.ui.control.DropDown
        p3_ylcolor2                   matlab.ui.control.DropDown
        p3_ylcolor1                   matlab.ui.control.DropDown
        p3_ylparameter2               matlab.ui.control.DropDown
        p3_ylparameter1               matlab.ui.control.DropDown
        p3_yllinestylelabel           matlab.ui.control.Label
        p3_ylcolorlabel               matlab.ui.control.Label
        p3_ylparameterlabel           matlab.ui.control.Label
        YaxisRightTab                 matlab.ui.container.Tab
        GridLayout2_6                 matlab.ui.container.GridLayout
        p3_yrmarkeredgecolor8         matlab.ui.control.DropDown
        p3_yrmarkeredgecolor7         matlab.ui.control.DropDown
        p3_yrmarkeredgecolor6         matlab.ui.control.DropDown
        p3_yrmarkeredgecolor5         matlab.ui.control.DropDown
        p3_yrmarkeredgecolor4         matlab.ui.control.DropDown
        p3_yrmarkeredgecolor3         matlab.ui.control.DropDown
        p3_yrmarkeredgecolor2         matlab.ui.control.DropDown
        p3_yrmarkeredgecolor1         matlab.ui.control.DropDown
        p3_yrmarkeredgecolorlabel     matlab.ui.control.Label
        p3_yrmode2label               matlab.ui.control.Label
        p3_yrmodelabel                matlab.ui.control.Label
        p3_yrcheckbox18               matlab.ui.control.CheckBox
        p3_yrcheckbox17               matlab.ui.control.CheckBox
        p3_yrcheckbox16               matlab.ui.control.CheckBox
        p3_yrcheckbox15               matlab.ui.control.CheckBox
        p3_yrcheckbox14               matlab.ui.control.CheckBox
        p3_yrcheckbox13               matlab.ui.control.CheckBox
        p3_yrcheckbox12               matlab.ui.control.CheckBox
        p3_yrcheckbox11               matlab.ui.control.CheckBox
        p3_yrcheckbox8                matlab.ui.control.CheckBox
        p3_yrcheckbox7                matlab.ui.control.CheckBox
        p3_yrcheckbox6                matlab.ui.control.CheckBox
        p3_yrcheckbox5                matlab.ui.control.CheckBox
        p3_yrcheckbox4                matlab.ui.control.CheckBox
        p3_yrcheckbox3                matlab.ui.control.CheckBox
        p3_yrcheckbox1                matlab.ui.control.CheckBox
        p3_yrcheckbox2                matlab.ui.control.CheckBox
        p3_yrmarkercolor8             matlab.ui.control.DropDown
        p3_yrmarker7                  matlab.ui.control.DropDown
        p3_yrmarker8                  matlab.ui.control.DropDown
        p3_yrmarkercolor7             matlab.ui.control.DropDown
        p3_yrmarkercolor5             matlab.ui.control.DropDown
        p3_yrmarker5                  matlab.ui.control.DropDown
        p3_yrmarkercolor6             matlab.ui.control.DropDown
        p3_yrmarker6                  matlab.ui.control.DropDown
        p3_yrmarkercolor4             matlab.ui.control.DropDown
        p3_yrmarker4                  matlab.ui.control.DropDown
        p3_yrmarkercolor3             matlab.ui.control.DropDown
        p3_yrmarker3                  matlab.ui.control.DropDown
        p3_yrmarkersize6              matlab.ui.control.NumericEditField
        p3_yrmarkersize5              matlab.ui.control.NumericEditField
        p3_yrmarkersize8              matlab.ui.control.NumericEditField
        p3_yrmarkersize7              matlab.ui.control.NumericEditField
        p3_yrlinewidth6               matlab.ui.control.NumericEditField
        p3_yrmarkersize4              matlab.ui.control.NumericEditField
        p3_yrmarkersize3              matlab.ui.control.NumericEditField
        p3_yrlinewidth8               matlab.ui.control.NumericEditField
        p3_yrlinewidth7               matlab.ui.control.NumericEditField
        p3_yrlinewidth4               matlab.ui.control.NumericEditField
        p3_yrlinewidth5               matlab.ui.control.NumericEditField
        p3_yrlinewidth3               matlab.ui.control.NumericEditField
        p3_yrlinestyle8               matlab.ui.control.DropDown
        p3_yrlinestyle7               matlab.ui.control.DropDown
        p3_yrlinestyle6               matlab.ui.control.DropDown
        p3_yrlinestyle5               matlab.ui.control.DropDown
        p3_yrlinestyle4               matlab.ui.control.DropDown
        p3_yrlinestyle3               matlab.ui.control.DropDown
        p3_yrcolor8                   matlab.ui.control.DropDown
        p3_yrcolor7                   matlab.ui.control.DropDown
        p3_yrcolor6                   matlab.ui.control.DropDown
        p3_yrcolor5                   matlab.ui.control.DropDown
        p3_yrcolor4                   matlab.ui.control.DropDown
        p3_yrcolor3                   matlab.ui.control.DropDown
        p3_yrparameter8               matlab.ui.control.DropDown
        p3_yrparameter7               matlab.ui.control.DropDown
        p3_yrparameter6               matlab.ui.control.DropDown
        p3_yrparameter5               matlab.ui.control.DropDown
        p3_yrparameter4               matlab.ui.control.DropDown
        p3_yrparameter3               matlab.ui.control.DropDown
        p3_yrmarkercolor2             matlab.ui.control.DropDown
        p3_yrmarker2                  matlab.ui.control.DropDown
        p3_yrmarkercolor1             matlab.ui.control.DropDown
        p3_yrmarker1                  matlab.ui.control.DropDown
        p3_yrlinewidth2               matlab.ui.control.NumericEditField
        p3_yrmarkersize2              matlab.ui.control.NumericEditField
        p3_yrmarkersize1              matlab.ui.control.NumericEditField
        p3_yrlinewidth1               matlab.ui.control.NumericEditField
        p3_yrmarkersizelabel          matlab.ui.control.Label
        p3_yrmarkerfacecolorlabel     matlab.ui.control.Label
        p3_yrlinewidthlabel           matlab.ui.control.Label
        p3_yrmarkerlabel              matlab.ui.control.Label
        p3_yrlinestyle2               matlab.ui.control.DropDown
        p3_yrlinestyle1               matlab.ui.control.DropDown
        p3_yrcolor2                   matlab.ui.control.DropDown
        p3_yrcolor1                   matlab.ui.control.DropDown
        p3_yrparameter2               matlab.ui.control.DropDown
        p3_yrparameter1               matlab.ui.control.DropDown
        p3_yrlinestylelabel           matlab.ui.control.Label
        p3_yrcolorlabel               matlab.ui.control.Label
        p3_yrparameterlabel           matlab.ui.control.Label
        Tab                           matlab.ui.container.Tab
        Fileslectgridlayout           matlab.ui.container.GridLayout
        p3_file8enable                matlab.ui.control.CheckBox
        p3_file7enable                matlab.ui.control.CheckBox
        p3_file6enable                matlab.ui.control.CheckBox
        p3_file5enable                matlab.ui.control.CheckBox
        p3_file4enable                matlab.ui.control.CheckBox
        p3_file3enable                matlab.ui.control.CheckBox
        p3_file2enable                matlab.ui.control.CheckBox
        p3_file1enable                matlab.ui.control.CheckBox
        p3_Filename8                  matlab.ui.control.EditField
        FilenameEditField_8Label      matlab.ui.control.Label
        p3_Filename7                  matlab.ui.control.EditField
        FilenameEditField_7Label      matlab.ui.control.Label
        p3_Filename6                  matlab.ui.control.EditField
        FilenameEditField_6Label      matlab.ui.control.Label
        p3_Filename5                  matlab.ui.control.EditField
        FilenameEditField_5Label      matlab.ui.control.Label
        p3_Filename4                  matlab.ui.control.EditField
        FilenameEditField_4Label      matlab.ui.control.Label
        p3_Filename3                  matlab.ui.control.EditField
        FilenameEditField_3Label      matlab.ui.control.Label
        p3_Filename2                  matlab.ui.control.EditField
        FilenameEditField_2Label      matlab.ui.control.Label
        p3_Filename1                  matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        p3_File8Button                matlab.ui.control.Button
        p3_File7Button                matlab.ui.control.Button
        p3_File6Button                matlab.ui.control.Button
        p3_File5Button                matlab.ui.control.Button
        p3_File4Button                matlab.ui.control.Button
        p3_File3Button                matlab.ui.control.Button
        p3_File2Button                matlab.ui.control.Button
        p3_File1Button                matlab.ui.control.Button
    end

    properties (Access = public)   
        p2_data;
        p2_variables;
        p2_speedsetval;
        p2_voltagesetval;
        p2_string0;p2_string1;p2_string2;p2_string3;p2_string4;p2_string5;
        p2_string6;p2_string7;p2_string8;p2_string9;p2_string10;
        p2_string11;p2_string12;p2_string13;p2_string14;p2_string15;
        p2_string16;p2_string17;p2_string18;p2_string19;p2_string20; 
        p2_string21;
        p2_string99; 
        p2_strct=struct;
        p2_t21=struct;
        p2_plotbtn=0;
        p2_t99p1;p2_t99p2;p2_t99p3;p2_t99p4;
        p2_t99p5;p2_t99p6;p2_t99p7;p2_t99p8;
        p2_t99p9;p2_t99p10;p2_t99p11;p2_t99p12;

        p3_data;
        p3_variables;
        p3_axisnumber;
        p3_scaling_struct=struct;
        
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p3_scaling_struct.min=[0,0,0];
            app.p3_scaling_struct.max=[1,1,1];
            app.p3_scaling_struct.step=[0.1,0.1,0.1];
            app.p3_scaling_struct.grid=["off","off","off"];
            app.p3_scaling_struct.axislabel=["Xaxis","Yaxis(left)","Yaxis(right)"];
            app.p3_scaling_struct.color=["black","black","black"];
            app.p3_scaling_struct.mode=[0,0,0];
            app.p3_axisnumber=1;

            app.p3_scalingmax.Value=1;
            app.p3_scalingstep.Value=0.1;
            app.p3_scalingaxislabel.Value="Xaxis";
            app.p3_scalingaxiscolor.Value='black';
        end

        % Button pushed function: p2_PlotButton
        function p2_PlotButton_2Pushed(app, event)
            p2f_plot(app);
        end

        % Value changed function: p2_VoltageSetValueDropDown
        function p2_VoltageSetValueDropDownValueChanged(app, event)
            p2f_Vdrpdwn(app);
        end

        % Value changed function: p2_SpeedSetValueDropDown
        function p2_SpeedSetValueDropDownValueChanged(app, event)
            p2f_Sdrpdwn(app);
        end

        % Value changed function: p2_t21Switch
        function p2_t21SwitchValueChanged(app, event)
            p2f_t21switch(app);
        end

        % Value changed function: p2_ManualScalingCheckBox
        function p2_ManualScalingCheckBoxValueChanged(app, event)
            p2f_scaling(app);   
        end

        % Button pushed function: p2_SelectFileButton
        function p2_SelectFileButtonPushed(app, event)
            p2f_fileselected(app);
        end

        % Value changed function: p2_opt0
        function p2_opt0ValueChanged(app, event)
            value = app.p2_opt0.Value;
            if value==1                                     %if checkbox 1 is clicked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p1.Visible="on";                      %plot vibration1
                 else
                    app.p2_t99p5.Visible="on";                 %else plot temp1
                 end   
            else                                            %if checkbox 1 is unchecked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p1.Visible="off";                      %hide plot vibration1
                 else
                    app.p2_t99p5.Visible="off";                 %else hide plot temp1
                 end    
             end
            
        end

        % Value changed function: p2_opt1
        function p2_opt1ValueChanged(app, event)
            value = app.p2_opt1.Value;
            if value==1                                     %if checkbox 1 is clicked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p2.Visible="on";                      %plot vibration1
                 else
                    app.p2_t99p6.Visible="on";                 %else plot temp1
                 end   
            else                                            %if checkbox 1 is unchecked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p2.Visible="off";                      %hide plot vibration1
                 else
                    app.p2_t99p6.Visible="off";                 %else hide plot temp1
                 end    
             end
        end

        % Value changed function: p2_opt2
        function p2_opt2ValueChanged(app, event)
            value = app.p2_opt2.Value;
            if value==1                                     %if checkbox 1 is clicked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p3.Visible="on";                      %plot vibration1
                 else
                    app.p2_t99p7.Visible="on";                 %else plot temp1
                 end   
            else                                            %if checkbox 1 is unchecked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p3.Visible="off";                      %hide plot vibration1
                 else
                    app.p2_t99p7.Visible="off";                 %else hide plot temp1
                 end    
             end
        end

        % Value changed function: p2_opt3
        function p2_opt3ValueChanged(app, event)
            value = app.p2_opt3.Value;
            if value==1                                     %if checkbox 1 is clicked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p4.Visible="on";                      %plot vibration1
                 else
                    app.p2_t99p8.Visible="on";                 %else plot temp1
                 end   
            else                                            %if checkbox 1 is unchecked
                 if app.p2_t99_Switch.Value=="Vibration"        %if vibration is selected
                    app.p2_t99p4.Visible="off";                      %hide plot vibration1
                 else
                    app.p2_t99p8.Visible="off";                 %else hide plot temp1
                 end    
             end
        end

        % Value changed function: p2_opt4
        function p2_opt4ValueChanged(app, event)
            value = app.p2_opt4.Value;
            if value==1                                     
                    app.p2_t99p9.Visible="on";                                   
            else                                           
                    app.p2_t99p9.Visible="off";                      
             end
        end

        % Value changed function: p2_opt5
        function p2_opt5ValueChanged(app, event)
            value = app.p2_opt5.Value;
            if value==1                                     
                    app.p2_t99p10.Visible="on";                                   
            else                                           
                    app.p2_t99p10.Visible="off";                      
             end
        end

        % Value changed function: p2_opt6
        function p2_opt6ValueChanged(app, event)
            value = app.p2_opt6.Value;
            if value==1                                     
                    app.p2_t99p11.Visible="on";                                   
            else                                           
                    app.p2_t99p11.Visible="off";                      
             end
        end

        % Value changed function: p2_opt7
        function p2_opt7ValueChanged(app, event)
            value = app.p2_opt7.Value;
            if value==1                                     
                    app.p2_t99p12.Visible="on";                                   
            else                                           
                    app.p2_t99p12.Visible="off";                      
             end
        end

        % Button pushed function: SelectFileButton
        function p3_SelectFileButtonPushed(app, event)
            p3f_fileselected(app);
        end

        % Value changed function: p3_scalingaxisname
        function p3_scalingaxisnameValueChanged(app, event)
            value = app.p3_scalingaxisname.Value;
            str=["Xaxis","Yaxis(left)","Yaxis(right)"];
            TF = contains(str,value);
            if TF(1) == 1
                m=1;
            elseif TF(2) == 1
                m=2;
            else
                m=3; 
            end
            app.p3_axisnumber=m;
            app.p3_scalingmin.Value=app.p3_scaling_struct.min(m);
            app.p3_scalingmax.Value=app.p3_scaling_struct.max(m);
            app.p3_scalingstep.Value=app.p3_scaling_struct.step(m);
            app.p3_scalinggrid.Value=app.p3_scaling_struct.grid(m);
            app.p3_scalingaxislabel.Value=app.p3_scaling_struct.axislabel(m);
            app.p3_scalingaxiscolor.Value=app.p3_scaling_struct.color(m);
            app.p3_scalingmode.Value=app.p3_scaling_struct.mode(m); 
        end

        % Button pushed function: PlotButton
        function p3_PlotButtonPushed(app, event)
            p3f_plot(app);
        end

        % Value changed function: p3_scalingmin
        function p3_scalingminValueChanged(app, event)
            value = app.p3_scalingmin.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.min(m)=value;
        end

        % Value changed function: p3_scalingmax
        function p3_scalingmaxValueChanged(app, event)
            value = app.p3_scalingmax.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.max(m)=value;
        end

        % Value changed function: p3_scalingstep
        function p3_scalingstepValueChanged(app, event)
            value = app.p3_scalingstep.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.step(m)=value;
        end

        % Value changed function: p3_scalingaxislabel
        function p3_scalingaxislabelValueChanged(app, event)
            value = app.p3_scalingaxislabel.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.axislabel(m)=value;
        end

        % Value changed function: p3_scalingaxiscolor
        function p3_scalingaxiscolorValueChanged(app, event)
            value = app.p3_scalingaxiscolor.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.color(m)=value;
        end

        % Value changed function: p3_scalingmode
        function p3_scalingmodeValueChanged(app, event)
            value = app.p3_scalingmode.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.mode(m)=value;

            app.p3_scaling_struct.min(m)=app.p3_scalingmin.Value;
            app.p3_scaling_struct.max(m)=app.p3_scalingmax.Value;
            app.p3_scaling_struct.step(m)=app.p3_scalingstep.Value;
            app.p3_scaling_struct.grid(m)=app.p3_scalinggrid.Value;
            app.p3_scaling_struct.axislabel(m)=app.p3_scalingaxislabel.Value;
            app.p3_scaling_struct.color(m)=app.p3_scalingaxiscolor.Value;
            app.p3_scaling_struct.mode(m)=app.p3_scalingmode.Value; 

        end

        % Value changed function: p3_scalinggrid
        function p3_scalinggridValueChanged(app, event)
            value = app.p3_scalinggrid.Value;
            m=app.p3_axisnumber;
            app.p3_scaling_struct.grid(m)=value;

            if app.p3_axisnumber == 2
                 app.p3_scaling_struct.grid(3)=value;
            elseif app.p3_axisnumber == 3
                 app.p3_scaling_struct.grid(2)=value;
            end    


        end

        % Button pushed function: p3_File1Button
        function p3_File1ButtonPushed(app, event)
            
        end

        % Button pushed function: p3_File2Button
        function p3_File2ButtonPushed(app, event)
            
        end

        % Button pushed function: p3_File3Button
        function p3_File3ButtonPushed(app, event)
            
        end

        % Button pushed function: p3_File4Button
        function p3_File4ButtonPushed(app, event)
            
        end

        % Button pushed function: p3_File5Button
        function p3_File5ButtonPushed(app, event)
            
        end

        % Button pushed function: p3_File6Button
        function p3_File6ButtonPushed(app, event)
            
        end

        % Button pushed function: p3_File7Button
        function p3_File7ButtonPushed(app, event)
            
        end

        % Button pushed function: p3_File8Button
        function p3_File8ButtonPushed(app, event)
            
        end

        % Value changed function: p3_file1enable
        function p3_file1enableValueChanged(app, event)
            value = app.p3_file1enable.Value;
            
        end

        % Value changed function: p3_file2enable
        function p3_file2enableValueChanged(app, event)
            value = app.p3_file2enable.Value;
            
        end

        % Value changed function: p3_file3enable
        function p3_file3enableValueChanged(app, event)
            value = app.p3_file3enable.Value;
            
        end

        % Value changed function: p3_file4enable
        function p3_file4enableValueChanged(app, event)
            value = app.p3_file4enable.Value;
            
        end

        % Value changed function: p3_file5enable
        function p3_file5enableValueChanged(app, event)
            value = app.p3_file5enable.Value;
            
        end

        % Value changed function: p3_file6enable
        function p3_file6enableValueChanged(app, event)
            value = app.p3_file6enable.Value;
            
        end

        % Value changed function: p3_file7enable
        function p3_file7enableValueChanged(app, event)
            value = app.p3_file7enable.Value;
            
        end

        % Value changed function: p3_file8enable
        function p3_file8enableValueChanged(app, event)
            value = app.p3_file8enable.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.p2_ResultTab = uitab(app.TabGroup);
            app.p2_ResultTab.Title = 'Inspection_Items';
            app.p2_UIAxes = uiaxes(app.p2_ResultTab);
            xlabel(app.p2_UIAxes, 'X')
            ylabel(app.p2_UIAxes, 'Y')
            zlabel(app.p2_UIAxes, 'Z')
            app.p2_UIAxes2 = uiaxes(app.p2_ResultTab);
            xlabel(app.p2_UIAxes2, 'X')
            ylabel(app.p2_UIAxes2, 'Y')
            zlabel(app.p2_UIAxes2, 'Z')
            app.p2_UIAxes2.Visible = 'off';
            app.p2_UIAxes3 = uiaxes(app.p2_ResultTab);
            xlabel(app.p2_UIAxes3, 'X')
            ylabel(app.p2_UIAxes3, 'Y')
            zlabel(app.p2_UIAxes3, 'Z')
            app.p2_UIAxes3.Visible = 'off';
            app.p2_UIAxes4 = uiaxes(app.p2_ResultTab);
            xlabel(app.p2_UIAxes4, 'X')
            ylabel(app.p2_UIAxes4, 'Y')
            zlabel(app.p2_UIAxes4, 'Z')
            app.p2_UIAxes4.Visible = 'off';
            app.p2_PlotButton = uibutton(app.p2_ResultTab, 'push');
            app.p2_PlotButton.ButtonPushedFcn = createCallbackFcn(app, @p2_PlotButton_2Pushed, true);
            app.p2_PlotButton.Text = 'Plot';
            app.XminEditFieldLabel = uilabel(app.p2_ResultTab);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.p2_XminEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.XmaxEditFieldLabel = uilabel(app.p2_ResultTab);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.p2_XmaxEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.YminleftEditFieldLabel = uilabel(app.p2_ResultTab);
            app.YminleftEditFieldLabel.Text = 'Ymin(left)';
            app.p2_YminleftEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.YmaxleftEditFieldLabel = uilabel(app.p2_ResultTab);
            app.YmaxleftEditFieldLabel.Text = 'Ymax(left)';
            app.p2_YmaxleftEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.YminrightEditFieldLabel = uilabel(app.p2_ResultTab);
            app.YminrightEditFieldLabel.Text = 'Ymin(right)';
            app.p2_YminrightEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.YmaxrightEditField_2Label = uilabel(app.p2_ResultTab);
            app.YmaxrightEditField_2Label.Text = 'Ymax(right)';
            app.p2_YmaxrightEditField_2 = uieditfield(app.p2_ResultTab, 'numeric');
            app.p2_ManualScalingCheckBox = uicheckbox(app.p2_ResultTab);
            app.p2_ManualScalingCheckBox.ValueChangedFcn = createCallbackFcn(app, @p2_ManualScalingCheckBoxValueChanged, true);
            app.p2_ManualScalingCheckBox.Text = 'ManualScaling';
            app.TorqueSetValueDropDownLabel = uilabel(app.p2_ResultTab);
            app.TorqueSetValueDropDownLabel.Visible = 'off';
            app.TorqueSetValueDropDownLabel.Text = 'Torque Set Value';
            app.p2_TorqueSetValueDropDown = uidropdown(app.p2_ResultTab);
            app.p2_TorqueSetValueDropDown.Visible = 'off';
            app.VoltageSetValueDropDownLabel = uilabel(app.p2_ResultTab);
            app.VoltageSetValueDropDownLabel.Visible = 'off';
            app.VoltageSetValueDropDownLabel.Text = 'Voltage Set Value';
            app.p2_VoltageSetValueDropDown = uidropdown(app.p2_ResultTab);
            app.p2_VoltageSetValueDropDown.ValueChangedFcn = createCallbackFcn(app, @p2_VoltageSetValueDropDownValueChanged, true);
            app.p2_VoltageSetValueDropDown.Visible = 'off';
            app.p2_ButtonGroup_2 = uibuttongroup(app.p2_ResultTab);
            app.p2_ButtonGroup_2.Enable = 'off';
            app.p2_ButtonGroup_2.Visible = 'off';
            app.p2_T40ch1Button = uiradiobutton(app.p2_ButtonGroup_2);
            app.p2_T40ch1Button.Text = 'T40 ch1';
            app.p2_T40ch1Button.Value = true;
            app.p2_T40ch2Button = uiradiobutton(app.p2_ButtonGroup_2);
            app.p2_T40ch2Button.Text = 'T40 ch2';
            app.SpeedSetValueDropDownLabel = uilabel(app.p2_ResultTab);
            app.SpeedSetValueDropDownLabel.Visible = 'off';
            app.SpeedSetValueDropDownLabel.Text = 'Speed Set Value';
            app.p2_SpeedSetValueDropDown = uidropdown(app.p2_ResultTab);
            app.p2_SpeedSetValueDropDown.ValueChangedFcn = createCallbackFcn(app, @p2_SpeedSetValueDropDownValueChanged, true);
            app.p2_SpeedSetValueDropDown.Visible = 'off';
            app.StepEditFieldLabel = uilabel(app.p2_ResultTab);
            app.StepEditFieldLabel.Visible = 'off';
            app.StepEditFieldLabel.Text = 'Step';
            app.p2_StepEditField = uieditfield(app.p2_ResultTab, 'numeric');
            app.p2_StepEditField.Visible = 'off';
            app.p2_TableLabel = uilabel(app.p2_ResultTab);
            app.p2_TableLabel.Text = 'Table';
            app.p2_Label = uilabel(app.p2_ResultTab);
            app.p2_GraphLabel = uilabel(app.p2_ResultTab);
            app.p2_GraphLabel.Text = 'Graph';
            app.p2_voltagecbx = uicheckbox(app.p2_ResultTab);
            app.p2_voltagecbx.Enable = 'off';
            app.p2_voltagecbx.Visible = 'off';
            app.p2_voltagecbx.Text = '';
            app.p2_speedcbx = uicheckbox(app.p2_ResultTab);
            app.p2_speedcbx.Enable = 'off';
            app.p2_speedcbx.Visible = 'off';
            app.p2_speedcbx.Text = '';
            app.p2_torquecbx = uicheckbox(app.p2_ResultTab);
            app.p2_torquecbx.Enable = 'off';
            app.p2_torquecbx.Visible = 'off';
            app.p2_torquecbx.Text = '';
            app.p2_t21Switch = uiswitch(app.p2_ResultTab, 'slider');
            app.p2_t21Switch.Items = {'T-Angle', 'Mrate-Angle'};
            app.p2_t21Switch.ValueChangedFcn = createCallbackFcn(app, @p2_t21SwitchValueChanged, true);
            app.p2_t21Switch.Visible = 'off';
            app.p2_t21Switch.Value = 'T-Angle';
            app.p2_UITable2 = uitable(app.p2_ResultTab);
            app.p2_UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.p2_UITable2.RowName = {};
            app.p2_SelectFileButton = uibutton(app.p2_ResultTab, 'push');
            app.p2_SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @p2_SelectFileButtonPushed, true);
            app.p2_SelectFileButton.Text = 'Select File';
            app.p2_t99_Switch = uiswitch(app.p2_ResultTab, 'slider');
            app.p2_t99_Switch.Items = {'Vibration', 'Temperature'};
            app.p2_t99_Switch.Visible = 'off';
            app.p2_t99_Switch.Value = 'Vibration';
            app.p2_opt0 = uicheckbox(app.p2_ResultTab);
            app.p2_opt0.ValueChangedFcn = createCallbackFcn(app, @p2_opt0ValueChanged, true);
            app.p2_opt0.Enable = 'off';
            app.p2_opt0.Visible = 'off';
            app.p2_opt0.Text = '1';
            app.p2_opt1 = uicheckbox(app.p2_ResultTab);
            app.p2_opt1.ValueChangedFcn = createCallbackFcn(app, @p2_opt1ValueChanged, true);
            app.p2_opt1.Enable = 'off';
            app.p2_opt1.Visible = 'off';
            app.p2_opt1.Text = '2';
            app.p2_opt2 = uicheckbox(app.p2_ResultTab);
            app.p2_opt2.ValueChangedFcn = createCallbackFcn(app, @p2_opt2ValueChanged, true);
            app.p2_opt2.Enable = 'off';
            app.p2_opt2.Visible = 'off';
            app.p2_opt2.Text = '3';
            app.p2_opt3 = uicheckbox(app.p2_ResultTab);
            app.p2_opt3.ValueChangedFcn = createCallbackFcn(app, @p2_opt3ValueChanged, true);
            app.p2_opt3.Enable = 'off';
            app.p2_opt3.Visible = 'off';
            app.p2_opt3.Text = '4';
            app.p2_opt4 = uicheckbox(app.p2_ResultTab);
            app.p2_opt4.ValueChangedFcn = createCallbackFcn(app, @p2_opt4ValueChanged, true);
            app.p2_opt4.Enable = 'off';
            app.p2_opt4.Visible = 'off';
            app.p2_opt4.Text = '5';
            app.p2_opt5 = uicheckbox(app.p2_ResultTab);
            app.p2_opt5.ValueChangedFcn = createCallbackFcn(app, @p2_opt5ValueChanged, true);
            app.p2_opt5.Enable = 'off';
            app.p2_opt5.Visible = 'off';
            app.p2_opt5.Text = '6';
            app.p2_opt6 = uicheckbox(app.p2_ResultTab);
            app.p2_opt6.ValueChangedFcn = createCallbackFcn(app, @p2_opt6ValueChanged, true);
            app.p2_opt6.Enable = 'off';
            app.p2_opt6.Visible = 'off';
            app.p2_opt6.Text = '7';
            app.p2_opt7 = uicheckbox(app.p2_ResultTab);
            app.p2_opt7.ValueChangedFcn = createCallbackFcn(app, @p2_opt7ValueChanged, true);
            app.p2_opt7.Enable = 'off';
            app.p2_opt7.Visible = 'off';
            app.p2_opt7.Text = '8';
            app.p2_FreePlotTab = uitab(app.TabGroup);
            app.p2_FreePlotTab.Title = 'FreePlot';
            app.TabGroup2 = uitabgroup(app.p2_FreePlotTab);
            app.GraphTab = uitab(app.TabGroup2);
            app.GraphTab.Title = 'Graph';
            app.GridLayout7 = uigridlayout(app.GraphTab);
            app.p3_UIAxes = uiaxes(app.GridLayout7);
            xlabel(app.p3_UIAxes, 'X')
            ylabel(app.p3_UIAxes, 'Y')
            zlabel(app.p3_UIAxes, 'Z')
            app.PlotButton = uibutton(app.GridLayout7, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @p3_PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ManualscalingPanel = uipanel(app.GridLayout7);
            app.ManualscalingPanel.Title = '               Manual scaling';
            app.GridLayout8 = uigridlayout(app.ManualscalingPanel);
            app.p3_scalingaxisnamelabel = uilabel(app.GridLayout8);
            app.p3_scalingaxisnamelabel.Text = 'Axis';
            app.p3_scalingmin = uieditfield(app.GridLayout8, 'numeric');
            app.p3_scalingmin.ValueChangedFcn = createCallbackFcn(app, @p3_scalingminValueChanged, true);
            app.p3_scalingmax = uieditfield(app.GridLayout8, 'numeric');
            app.p3_scalingmax.ValueChangedFcn = createCallbackFcn(app, @p3_scalingmaxValueChanged, true);
            app.p3_scalingstep = uieditfield(app.GridLayout8, 'numeric');
            app.p3_scalingstep.ValueChangedFcn = createCallbackFcn(app, @p3_scalingstepValueChanged, true);
            app.p3_scalingminlabel = uilabel(app.GridLayout8);
            app.p3_scalingminlabel.Text = 'min';
            app.p3_scalingmaxlabel = uilabel(app.GridLayout8);
            app.p3_scalingmaxlabel.Text = 'max';
            app.p3_scalingmsteplabel = uilabel(app.GridLayout8);
            app.p3_scalingmsteplabel.Text = 'step';
            app.p3_scalingmode = uicheckbox(app.GridLayout8);
            app.p3_scalingmode.ValueChangedFcn = createCallbackFcn(app, @p3_scalingmodeValueChanged, true);
            app.p3_scalingmode.Tooltip = {'If selected, all the above options will be set else they will be set automatically.'};
            app.p3_scalingmode.Text = '';
            app.p3_scalingmodelabel = uilabel(app.GridLayout8);
            app.p3_scalingmodelabel.Text = 'Auto scaling';
            app.p3_scalingaxisname = uidropdown(app.GridLayout8);
            app.p3_scalingaxisname.Items = {'Xaxis', 'Yaxis(left)', 'Yaxis(right)', ''};
            app.p3_scalingaxisname.ValueChangedFcn = createCallbackFcn(app, @p3_scalingaxisnameValueChanged, true);
            app.p3_scalingaxisname.Value = 'Xaxis';
            app.p3_scalingaxislabellabel = uilabel(app.GridLayout8);
            app.p3_scalingaxislabellabel.Text = 'Axis label';
            app.p3_scalingaxislabel = uieditfield(app.GridLayout8, 'text');
            app.p3_scalingaxislabel.ValueChangedFcn = createCallbackFcn(app, @p3_scalingaxislabelValueChanged, true);
            app.p3_scalingcolorlabel = uilabel(app.GridLayout8);
            app.p3_scalingcolorlabel.Text = 'Axis color';
            app.p3_scalingaxiscolor = uidropdown(app.GridLayout8);
            app.p3_scalingaxiscolor.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white', ''};
            app.p3_scalingaxiscolor.ValueChangedFcn = createCallbackFcn(app, @p3_scalingaxiscolorValueChanged, true);
            app.p3_scalingaxiscolor.Value = 'red';
            app.p3_scalingaxisgridlabel = uilabel(app.GridLayout8);
            app.p3_scalingaxisgridlabel.Text = 'Axis grid';
            app.p3_scalinggrid = uidropdown(app.GridLayout8);
            app.p3_scalinggrid.Items = {'off', 'on', ''};
            app.p3_scalinggrid.ValueChangedFcn = createCallbackFcn(app, @p3_scalinggridValueChanged, true);
            app.p3_scalinggrid.Tooltip = {'If value is changed for one of the Yaxes, the other value will also be changed.'};
            app.p3_scalinggrid.Value = 'off';
            app.SelectFileButton = uibutton(app.GridLayout7, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @p3_SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'SelectFile';
            app.XaxisTab_2 = uitab(app.TabGroup2);
            app.XaxisTab_2.Title = 'Xaxis';
            app.GridLayout2_3 = uigridlayout(app.XaxisTab_2);
            app.p3_xparameterlabel = uilabel(app.GridLayout2_3);
            app.p3_xparameterlabel.Text = 'Parameter';
            app.p3_xparameter1 = uidropdown(app.GridLayout2_3);
            app.YaxisLeftTab = uitab(app.TabGroup2);
            app.YaxisLeftTab.Title = 'Yaxis(Left)';
            app.GridLayout2_5 = uigridlayout(app.YaxisLeftTab);
            app.p3_ylparameterlabel = uilabel(app.GridLayout2_5);
            app.p3_ylparameterlabel.Text = 'Parameter';
            app.p3_ylcolorlabel = uilabel(app.GridLayout2_5);
            app.p3_ylcolorlabel.Text = 'Color';
            app.p3_yllinestylelabel = uilabel(app.GridLayout2_5);
            app.p3_yllinestylelabel.Text = 'Line style';
            app.p3_ylparameter1 = uidropdown(app.GridLayout2_5);
            app.p3_ylparameter2 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor1 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor1.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylcolor1.Value = 'red';
            app.p3_ylcolor2 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor2.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylcolor2.Value = 'red';
            app.p3_yllinestyle1 = uidropdown(app.GridLayout2_5);
            app.p3_yllinestyle1.Items = {'-', '--', ':', '-.'};
            app.p3_yllinestyle1.Value = '-';
            app.p3_yllinestyle2 = uidropdown(app.GridLayout2_5);
            app.p3_yllinestyle2.Items = {'-', '--', ':', '-.'};
            app.p3_yllinestyle2.Value = '-';
            app.p3_ylmarkerlabel = uilabel(app.GridLayout2_5);
            app.p3_ylmarkerlabel.Text = 'Marker';
            app.p3_yllinewidthlabel = uilabel(app.GridLayout2_5);
            app.p3_yllinewidthlabel.Text = 'Line width';
            app.p3_ylmarkerfacecolorlabel = uilabel(app.GridLayout2_5);
            app.p3_ylmarkerfacecolorlabel.Text = 'Marker face color';
            app.p3_ylmarkersizeabel = uilabel(app.GridLayout2_5);
            app.p3_ylmarkersizeabel.Text = 'Marker size';
            app.p3_yllinewidth1 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth1.Tooltip = {'Default = 0.5'};
            app.p3_ylmarkersize1 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_ylmarkersize2 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth2 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth2.Tooltip = {'Default = 0.5'};
            app.p3_ylmarker1 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarker1.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.p3_ylmarker1.Value = 'o';
            app.p3_ylmarkercolor1 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkercolor1.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkercolor1.Value = 'red';
            app.p3_ylmarker2 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarker2.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.p3_ylmarker2.Value = 'o';
            app.p3_ylmarkercolor2 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkercolor2.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkercolor2.Value = 'red';
            app.p3_ylparameter3 = uidropdown(app.GridLayout2_5);
            app.p3_ylparameter4 = uidropdown(app.GridLayout2_5);
            app.p3_ylparameter5 = uidropdown(app.GridLayout2_5);
            app.p3_ylparameter6 = uidropdown(app.GridLayout2_5);
            app.p3_ylparameter7 = uidropdown(app.GridLayout2_5);
            app.p3_ylparameter8 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor3 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor3.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylcolor3.Value = 'red';
            app.p3_ylcolor4 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor4.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylcolor4.Value = 'red';
            app.p3_ylcolor5 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor5.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylcolor5.Value = 'red';
            app.p3_ylcolor6 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor6.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylcolor6.Value = 'red';
            app.p3_ylcolor7 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor7.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylcolor7.Value = 'red';
            app.p3_ylcolor8 = uidropdown(app.GridLayout2_5);
            app.p3_ylcolor8.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylcolor8.Value = 'red';
            app.p3_yllinestyle3 = uidropdown(app.GridLayout2_5);
            app.p3_yllinestyle3.Items = {'-', '--', ':', '-.'};
            app.p3_yllinestyle3.Value = '-';
            app.p3_yllinestyle4 = uidropdown(app.GridLayout2_5);
            app.p3_yllinestyle4.Items = {'-', '--', ':', '-.'};
            app.p3_yllinestyle4.Value = '-';
            app.p3_yllinestyle5 = uidropdown(app.GridLayout2_5);
            app.p3_yllinestyle5.Items = {'-', '--', ':', '-.'};
            app.p3_yllinestyle5.Value = '-';
            app.p3_yllinestyle6 = uidropdown(app.GridLayout2_5);
            app.p3_yllinestyle6.Items = {'-', '--', ':', '-.'};
            app.p3_yllinestyle6.Value = '-';
            app.p3_yllinestyle7 = uidropdown(app.GridLayout2_5);
            app.p3_yllinestyle7.Items = {'-', '--', ':', '-.'};
            app.p3_yllinestyle7.Value = '-';
            app.p3_yllinestyle8 = uidropdown(app.GridLayout2_5);
            app.p3_yllinestyle8.Items = {'-', '--', ':', '-.'};
            app.p3_yllinestyle8.Value = '-';
            app.p3_yllinewidth3 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth3.Tooltip = {'Default = 0.5'};
            app.p3_yllinewidth5 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth5.Tooltip = {'Default = 0.5'};
            app.p3_yllinewidth4 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth4.Tooltip = {'Default = 0.5'};
            app.p3_yllinewidth7 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth7.Tooltip = {'Default = 0.5'};
            app.p3_yllinewidth8 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth8.Tooltip = {'Default = 0.5'};
            app.p3_ylmarkersize3 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_ylmarkersize4 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth6 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_yllinewidth6.Tooltip = {'Default = 0.5'};
            app.p3_ylmarkersize7 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_ylmarkersize8 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_ylmarkersize5 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_ylmarkersize6 = uieditfield(app.GridLayout2_5, 'numeric');
            app.p3_ylmarker3 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarker3.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.p3_ylmarker3.Value = 'o';
            app.p3_ylmarkercolor3 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkercolor3.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkercolor3.Value = 'red';
            app.p3_ylmarker4 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarker4.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.p3_ylmarker4.Value = 'o';
            app.p3_ylmarkercolor4 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkercolor4.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkercolor4.Value = 'red';
            app.p3_ylmarker6 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarker6.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.p3_ylmarker6.Value = 'o';
            app.p3_ylmarkercolor6 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkercolor6.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkercolor6.Value = 'red';
            app.p3_ylmarker5 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarker5.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.p3_ylmarker5.Value = 'o';
            app.p3_ylmarkercolor5 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkercolor5.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkercolor5.Value = 'red';
            app.p3_ylmarkercolor7 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkercolor7.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkercolor7.Value = 'red';
            app.p3_ylmarker8 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarker8.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.p3_ylmarker8.Value = 'o';
            app.p3_ylmarker7 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarker7.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none'};
            app.p3_ylmarker7.Value = 'o';
            app.p3_ylmarkercolor8 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkercolor8.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkercolor8.Value = 'red';
            app.p3_ylcheckbox1 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox1.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_ylcheckbox1.Text = '';
            app.p3_ylcheckbox2 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox2.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_ylcheckbox2.Text = '';
            app.p3_ylcheckbox3 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox3.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_ylcheckbox3.Text = '';
            app.p3_ylcheckbox4 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox4.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_ylcheckbox4.Text = '';
            app.p3_ylcheckbox5 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox5.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_ylcheckbox5.Text = '';
            app.p3_ylcheckbox6 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox6.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_ylcheckbox6.Text = '';
            app.p3_ylcheckbox7 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox7.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_ylcheckbox7.Text = '';
            app.p3_ylcheckbox8 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox8.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_ylcheckbox8.Text = '';
            app.p3_ylcheckbox11 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox11.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_ylcheckbox11.Text = '';
            app.p3_ylcheckbox12 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox12.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_ylcheckbox12.Text = '';
            app.p3_ylcheckbox13 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox13.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_ylcheckbox13.Text = '';
            app.p3_ylcheckbox14 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox14.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_ylcheckbox14.Text = '';
            app.p3_ylcheckbox15 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox15.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_ylcheckbox15.Text = '';
            app.p3_ylcheckbox16 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox16.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_ylcheckbox16.Text = '';
            app.p3_ylcheckbox17 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox17.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_ylcheckbox17.Text = '';
            app.p3_ylcheckbox18 = uicheckbox(app.GridLayout2_5);
            app.p3_ylcheckbox18.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_ylcheckbox18.Text = '';
            app.p3_ylmodelabel = uilabel(app.GridLayout2_5);
            app.p3_ylmodelabel.Text = 'Mode';
            app.p3_ylmode2label = uilabel(app.GridLayout2_5);
            app.p3_ylmode2label.Text = 'Mode';
            app.p3_ylmarkeredgecolorlabel = uilabel(app.GridLayout2_5);
            app.p3_ylmarkeredgecolorlabel.Text = 'Marker edge color';
            app.p3_ylmarkeredgecolor5 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkeredgecolor5.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkeredgecolor5.Value = 'red';
            app.p3_ylmarkeredgecolor1 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkeredgecolor1.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkeredgecolor1.Value = 'red';
            app.p3_ylmarkeredgecolor6 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkeredgecolor6.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkeredgecolor6.Value = 'red';
            app.p3_ylmarkeredgecolor2 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkeredgecolor2.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkeredgecolor2.Value = 'red';
            app.p3_ylmarkeredgecolor7 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkeredgecolor7.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkeredgecolor7.Value = 'red';
            app.p3_ylmarkeredgecolor3 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkeredgecolor3.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkeredgecolor3.Value = 'red';
            app.p3_ylmarkeredgecolor8 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkeredgecolor8.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkeredgecolor8.Value = 'red';
            app.p3_ylmarkeredgecolor4 = uidropdown(app.GridLayout2_5);
            app.p3_ylmarkeredgecolor4.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_ylmarkeredgecolor4.Value = 'red';
            app.YaxisRightTab = uitab(app.TabGroup2);
            app.YaxisRightTab.Title = 'Yaxis(Right)';
            app.GridLayout2_6 = uigridlayout(app.YaxisRightTab);
            app.p3_yrparameterlabel = uilabel(app.GridLayout2_6);
            app.p3_yrparameterlabel.Text = 'Parameter';
            app.p3_yrcolorlabel = uilabel(app.GridLayout2_6);
            app.p3_yrcolorlabel.Text = 'Color';
            app.p3_yrlinestylelabel = uilabel(app.GridLayout2_6);
            app.p3_yrlinestylelabel.Text = 'Line style';
            app.p3_yrparameter1 = uidropdown(app.GridLayout2_6);
            app.p3_yrparameter2 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor1 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor1.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrcolor1.Value = 'red';
            app.p3_yrcolor2 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor2.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrcolor2.Value = 'red';
            app.p3_yrlinestyle1 = uidropdown(app.GridLayout2_6);
            app.p3_yrlinestyle1.Items = {'-', '--', ':', '-.', ''};
            app.p3_yrlinestyle1.Value = '-';
            app.p3_yrlinestyle2 = uidropdown(app.GridLayout2_6);
            app.p3_yrlinestyle2.Items = {'-', '--', ':', '-.', ''};
            app.p3_yrlinestyle2.Value = '-';
            app.p3_yrmarkerlabel = uilabel(app.GridLayout2_6);
            app.p3_yrmarkerlabel.Text = 'Marker';
            app.p3_yrlinewidthlabel = uilabel(app.GridLayout2_6);
            app.p3_yrlinewidthlabel.Text = 'Line width';
            app.p3_yrmarkerfacecolorlabel = uilabel(app.GridLayout2_6);
            app.p3_yrmarkerfacecolorlabel.Text = 'Marker face color';
            app.p3_yrmarkersizelabel = uilabel(app.GridLayout2_6);
            app.p3_yrmarkersizelabel.Text = 'Marker size';
            app.p3_yrlinewidth1 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarkersize1 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarkersize2 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrlinewidth2 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarker1 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarker1.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none', ''};
            app.p3_yrmarker1.Value = 'o';
            app.p3_yrmarkercolor1 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkercolor1.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkercolor1.Value = 'red';
            app.p3_yrmarker2 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarker2.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none', ''};
            app.p3_yrmarker2.Value = 'o';
            app.p3_yrmarkercolor2 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkercolor2.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkercolor2.Value = 'red';
            app.p3_yrparameter3 = uidropdown(app.GridLayout2_6);
            app.p3_yrparameter4 = uidropdown(app.GridLayout2_6);
            app.p3_yrparameter5 = uidropdown(app.GridLayout2_6);
            app.p3_yrparameter6 = uidropdown(app.GridLayout2_6);
            app.p3_yrparameter7 = uidropdown(app.GridLayout2_6);
            app.p3_yrparameter8 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor3 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor3.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrcolor3.Value = 'red';
            app.p3_yrcolor4 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor4.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrcolor4.Value = 'red';
            app.p3_yrcolor5 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor5.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrcolor5.Value = 'red';
            app.p3_yrcolor6 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor6.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrcolor6.Value = 'red';
            app.p3_yrcolor7 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor7.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrcolor7.Value = 'red';
            app.p3_yrcolor8 = uidropdown(app.GridLayout2_6);
            app.p3_yrcolor8.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrcolor8.Value = 'red';
            app.p3_yrlinestyle3 = uidropdown(app.GridLayout2_6);
            app.p3_yrlinestyle3.Items = {'-', '--', ':', '-.', ''};
            app.p3_yrlinestyle3.Value = '-';
            app.p3_yrlinestyle4 = uidropdown(app.GridLayout2_6);
            app.p3_yrlinestyle4.Items = {'-', '--', ':', '-.', ''};
            app.p3_yrlinestyle4.Value = '-';
            app.p3_yrlinestyle5 = uidropdown(app.GridLayout2_6);
            app.p3_yrlinestyle5.Items = {'-', '--', ':', '-.', ''};
            app.p3_yrlinestyle5.Value = '-';
            app.p3_yrlinestyle6 = uidropdown(app.GridLayout2_6);
            app.p3_yrlinestyle6.Items = {'-', '--', ':', '-.', ''};
            app.p3_yrlinestyle6.Value = '-';
            app.p3_yrlinestyle7 = uidropdown(app.GridLayout2_6);
            app.p3_yrlinestyle7.Items = {'-', '--', ':', '-.', ''};
            app.p3_yrlinestyle7.Value = '-';
            app.p3_yrlinestyle8 = uidropdown(app.GridLayout2_6);
            app.p3_yrlinestyle8.Items = {'-', '--', ':', '-.', ''};
            app.p3_yrlinestyle8.Value = '-';
            app.p3_yrlinewidth3 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrlinewidth5 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrlinewidth4 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrlinewidth7 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrlinewidth8 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarkersize3 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarkersize4 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrlinewidth6 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarkersize7 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarkersize8 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarkersize5 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarkersize6 = uieditfield(app.GridLayout2_6, 'numeric');
            app.p3_yrmarker3 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarker3.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none', ''};
            app.p3_yrmarker3.Value = 'o';
            app.p3_yrmarkercolor3 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkercolor3.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkercolor3.Value = 'red';
            app.p3_yrmarker4 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarker4.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none', ''};
            app.p3_yrmarker4.Value = 'o';
            app.p3_yrmarkercolor4 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkercolor4.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkercolor4.Value = 'red';
            app.p3_yrmarker6 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarker6.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none', ''};
            app.p3_yrmarker6.Value = 'o';
            app.p3_yrmarkercolor6 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkercolor6.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkercolor6.Value = 'red';
            app.p3_yrmarker5 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarker5.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none', ''};
            app.p3_yrmarker5.Value = 'o';
            app.p3_yrmarkercolor5 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkercolor5.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkercolor5.Value = 'red';
            app.p3_yrmarkercolor7 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkercolor7.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkercolor7.Value = 'red';
            app.p3_yrmarker8 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarker8.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none', ''};
            app.p3_yrmarker8.Value = 'o';
            app.p3_yrmarker7 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarker7.Items = {'o', '+', '*', '.', 'x', '_', '|', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram', 'none', ''};
            app.p3_yrmarker7.Value = 'o';
            app.p3_yrmarkercolor8 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkercolor8.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkercolor8.Value = 'red';
            app.p3_yrcheckbox2 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox2.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_yrcheckbox2.Text = '';
            app.p3_yrcheckbox1 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox1.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_yrcheckbox1.Text = '';
            app.p3_yrcheckbox3 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox3.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_yrcheckbox3.Text = '';
            app.p3_yrcheckbox4 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox4.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_yrcheckbox4.Text = '';
            app.p3_yrcheckbox5 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox5.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_yrcheckbox5.Text = '';
            app.p3_yrcheckbox6 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox6.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_yrcheckbox6.Text = '';
            app.p3_yrcheckbox7 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox7.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_yrcheckbox7.Text = '';
            app.p3_yrcheckbox8 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox8.Tooltip = {'Checked=Enables, unchecked=Disable'};
            app.p3_yrcheckbox8.Text = '';
            app.p3_yrcheckbox11 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox11.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_yrcheckbox11.Text = '';
            app.p3_yrcheckbox12 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox12.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_yrcheckbox12.Text = '';
            app.p3_yrcheckbox13 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox13.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_yrcheckbox13.Text = '';
            app.p3_yrcheckbox14 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox14.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_yrcheckbox14.Text = '';
            app.p3_yrcheckbox15 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox15.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_yrcheckbox15.Text = '';
            app.p3_yrcheckbox16 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox16.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_yrcheckbox16.Text = '';
            app.p3_yrcheckbox17 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox17.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_yrcheckbox17.Text = '';
            app.p3_yrcheckbox18 = uicheckbox(app.GridLayout2_6);
            app.p3_yrcheckbox18.Tooltip = {'Checked=Manual mode, unchecked=auto mode'};
            app.p3_yrcheckbox18.Text = '';
            app.p3_yrmodelabel = uilabel(app.GridLayout2_6);
            app.p3_yrmodelabel.Text = 'Mode';
            app.p3_yrmode2label = uilabel(app.GridLayout2_6);
            app.p3_yrmode2label.Text = 'Mode';
            app.p3_yrmarkeredgecolorlabel = uilabel(app.GridLayout2_6);
            app.p3_yrmarkeredgecolorlabel.Text = 'Marker edge color';
            app.p3_yrmarkeredgecolor1 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkeredgecolor1.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkeredgecolor1.Value = 'red';
            app.p3_yrmarkeredgecolor2 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkeredgecolor2.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkeredgecolor2.Value = 'red';
            app.p3_yrmarkeredgecolor3 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkeredgecolor3.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkeredgecolor3.Value = 'red';
            app.p3_yrmarkeredgecolor4 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkeredgecolor4.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkeredgecolor4.Value = 'red';
            app.p3_yrmarkeredgecolor5 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkeredgecolor5.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkeredgecolor5.Value = 'red';
            app.p3_yrmarkeredgecolor6 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkeredgecolor6.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkeredgecolor6.Value = 'red';
            app.p3_yrmarkeredgecolor7 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkeredgecolor7.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkeredgecolor7.Value = 'red';
            app.p3_yrmarkeredgecolor8 = uidropdown(app.GridLayout2_6);
            app.p3_yrmarkeredgecolor8.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.p3_yrmarkeredgecolor8.Value = 'red';
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = 'Tab';
            app.Fileslectgridlayout = uigridlayout(app.Tab);
            app.p3_File1Button = uibutton(app.Fileslectgridlayout, 'push');
            app.p3_File1Button.ButtonPushedFcn = createCallbackFcn(app, @p3_File1ButtonPushed, true);
            app.p3_File1Button.Text = 'File1';
            app.p3_File2Button = uibutton(app.Fileslectgridlayout, 'push');
            app.p3_File2Button.ButtonPushedFcn = createCallbackFcn(app, @p3_File2ButtonPushed, true);
            app.p3_File2Button.Text = 'File2';
            app.p3_File3Button = uibutton(app.Fileslectgridlayout, 'push');
            app.p3_File3Button.ButtonPushedFcn = createCallbackFcn(app, @p3_File3ButtonPushed, true);
            app.p3_File3Button.Text = 'File3';
            app.p3_File4Button = uibutton(app.Fileslectgridlayout, 'push');
            app.p3_File4Button.ButtonPushedFcn = createCallbackFcn(app, @p3_File4ButtonPushed, true);
            app.p3_File4Button.Text = 'File4';
            app.p3_File5Button = uibutton(app.Fileslectgridlayout, 'push');
            app.p3_File5Button.ButtonPushedFcn = createCallbackFcn(app, @p3_File5ButtonPushed, true);
            app.p3_File5Button.Text = 'File5';
            app.p3_File6Button = uibutton(app.Fileslectgridlayout, 'push');
            app.p3_File6Button.ButtonPushedFcn = createCallbackFcn(app, @p3_File6ButtonPushed, true);
            app.p3_File6Button.Text = 'File6';
            app.p3_File7Button = uibutton(app.Fileslectgridlayout, 'push');
            app.p3_File7Button.ButtonPushedFcn = createCallbackFcn(app, @p3_File7ButtonPushed, true);
            app.p3_File7Button.Text = 'File7';
            app.p3_File8Button = uibutton(app.Fileslectgridlayout, 'push');
            app.p3_File8Button.ButtonPushedFcn = createCallbackFcn(app, @p3_File8ButtonPushed, true);
            app.p3_File8Button.Text = 'File8';
            app.FilenameEditFieldLabel = uilabel(app.Fileslectgridlayout);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.p3_Filename1 = uieditfield(app.Fileslectgridlayout, 'text');
            app.FilenameEditField_2Label = uilabel(app.Fileslectgridlayout);
            app.FilenameEditField_2Label.Text = 'Filename';
            app.p3_Filename2 = uieditfield(app.Fileslectgridlayout, 'text');
            app.FilenameEditField_3Label = uilabel(app.Fileslectgridlayout);
            app.FilenameEditField_3Label.Text = 'Filename';
            app.p3_Filename3 = uieditfield(app.Fileslectgridlayout, 'text');
            app.FilenameEditField_4Label = uilabel(app.Fileslectgridlayout);
            app.FilenameEditField_4Label.Text = 'Filename';
            app.p3_Filename4 = uieditfield(app.Fileslectgridlayout, 'text');
            app.FilenameEditField_5Label = uilabel(app.Fileslectgridlayout);
            app.FilenameEditField_5Label.Text = 'Filename';
            app.p3_Filename5 = uieditfield(app.Fileslectgridlayout, 'text');
            app.FilenameEditField_6Label = uilabel(app.Fileslectgridlayout);
            app.FilenameEditField_6Label.Text = 'Filename';
            app.p3_Filename6 = uieditfield(app.Fileslectgridlayout, 'text');
            app.FilenameEditField_7Label = uilabel(app.Fileslectgridlayout);
            app.FilenameEditField_7Label.Text = 'Filename';
            app.p3_Filename7 = uieditfield(app.Fileslectgridlayout, 'text');
            app.FilenameEditField_8Label = uilabel(app.Fileslectgridlayout);
            app.FilenameEditField_8Label.Text = 'Filename';
            app.p3_Filename8 = uieditfield(app.Fileslectgridlayout, 'text');
            app.p3_file1enable = uicheckbox(app.Fileslectgridlayout);
            app.p3_file1enable.ValueChangedFcn = createCallbackFcn(app, @p3_file1enableValueChanged, true);
            app.p3_file1enable.Text = '';
            app.p3_file2enable = uicheckbox(app.Fileslectgridlayout);
            app.p3_file2enable.ValueChangedFcn = createCallbackFcn(app, @p3_file2enableValueChanged, true);
            app.p3_file2enable.Text = '';
            app.p3_file3enable = uicheckbox(app.Fileslectgridlayout);
            app.p3_file3enable.ValueChangedFcn = createCallbackFcn(app, @p3_file3enableValueChanged, true);
            app.p3_file3enable.Text = '';
            app.p3_file4enable = uicheckbox(app.Fileslectgridlayout);
            app.p3_file4enable.ValueChangedFcn = createCallbackFcn(app, @p3_file4enableValueChanged, true);
            app.p3_file4enable.Text = '';
            app.p3_file5enable = uicheckbox(app.Fileslectgridlayout);
            app.p3_file5enable.ValueChangedFcn = createCallbackFcn(app, @p3_file5enableValueChanged, true);
            app.p3_file5enable.Text = '';
            app.p3_file6enable = uicheckbox(app.Fileslectgridlayout);
            app.p3_file6enable.ValueChangedFcn = createCallbackFcn(app, @p3_file6enableValueChanged, true);
            app.p3_file6enable.Text = '';
            app.p3_file7enable = uicheckbox(app.Fileslectgridlayout);
            app.p3_file7enable.ValueChangedFcn = createCallbackFcn(app, @p3_file7enableValueChanged, true);
            app.p3_file7enable.Text = '';
            app.p3_file8enable = uicheckbox(app.Fileslectgridlayout);
            app.p3_file8enable.ValueChangedFcn = createCallbackFcn(app, @p3_file8enableValueChanged, true);
            app.p3_file8enable.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p2_StandaloneApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end